classdef aes_image_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        FileMenu                   matlab.ui.container.Menu
        LoadImageMenu              matlab.ui.container.Menu
        LoadROIsMenu               matlab.ui.container.Menu
        SaveROIsMenu               matlab.ui.container.Menu
        ExportAESMaskMenu          matlab.ui.container.Menu
        ExportROIMasksMenu         matlab.ui.container.Menu
        ImageMenu                  matlab.ui.container.Menu
        WindowLevelMenu            matlab.ui.container.Menu
        BidirectionalShiftMenu     matlab.ui.container.Menu
        DemixCrosstalkMenu         matlab.ui.container.Menu
        GridLayout3                matlab.ui.container.GridLayout
        ROIPanel                   matlab.ui.container.Panel
        GridLayout9                matlab.ui.container.GridLayout
        ROIDeleteButton            matlab.ui.control.Button
        ThresholdMaskPanel         matlab.ui.container.Panel
        GridLayout10               matlab.ui.container.GridLayout
        MaxHoleSpinner             matlab.ui.control.Spinner
        MaxHoleSpinnerLabel        matlab.ui.control.Label
        ThresholdButton            matlab.ui.control.Button
        BlockSliceOverlapCheckBox  matlab.ui.control.CheckBox
        ApplytoAllSlicesCheckBox   matlab.ui.control.CheckBox
        BorderSpinner              matlab.ui.control.Spinner
        BorderSpinnerLabel         matlab.ui.control.Label
        ShapesPanel                matlab.ui.container.Panel
        GridLayout6                matlab.ui.container.GridLayout
        ROIField6                  matlab.ui.control.NumericEditField
        ROILabel6                  matlab.ui.control.Label
        ROIField5                  matlab.ui.control.NumericEditField
        ROILabel5                  matlab.ui.control.Label
        ROIField4                  matlab.ui.control.NumericEditField
        ROILabel4                  matlab.ui.control.Label
        ROIField3                  matlab.ui.control.NumericEditField
        ROILabel3                  matlab.ui.control.Label
        ROIField2                  matlab.ui.control.NumericEditField
        ROILabel2                  matlab.ui.control.Label
        ROIField1                  matlab.ui.control.NumericEditField
        ROILabel1                  matlab.ui.control.Label
        ROIShapeDropDown           matlab.ui.control.DropDown
        ROIShapeDropDownLabel      matlab.ui.control.Label
        ROIAddButton               matlab.ui.control.Button
        ImagePanel                 matlab.ui.container.Panel
        GridLayout2                matlab.ui.container.GridLayout
        StackNavigationPanel       matlab.ui.container.Panel
        GridLayout7                matlab.ui.container.GridLayout
        FrameParityButton          matlab.ui.control.StateButton
        FrameParityLabel           matlab.ui.control.Label
        ChannelSpinner             matlab.ui.control.Spinner
        ChannelSpinnerLabel        matlab.ui.control.Label
        SliceSpinner               matlab.ui.control.Spinner
        SliceSpinnerLabel          matlab.ui.control.Label
        OccupancyPanel             matlab.ui.container.Panel
        GridLayout8                matlab.ui.container.GridLayout
        OccTotLabel                matlab.ui.control.Label
        TotalLabel                 matlab.ui.control.Label
        OccChLabel                 matlab.ui.control.Label
        ChannelLabel               matlab.ui.control.Label
        OccLabel                   matlab.ui.control.Label
        ImageLabel                 matlab.ui.control.Label
        imagePlot                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % related to file management
        tempFile
        tempPath
        file
        path

        % stack structure variables
        channel
        channels
        slice
        slices
        sepChannels
        isVolume

        % image variables
        refImages
        dmxImages
        dispImage
        shiftImage
        contrImage
        BCwndw
        imhandle
        op = 0.4;
        op2 = 0.55;
        parityFlag
        W
        dmxBuff
        borderMask
        numPx

        % for y axis bidirectional images
        bidirectional
        frameParity
        yShift
        autoYShiftVal
        htot

        % roi elements
        shapeMaskHandler
        threshMaskHandler
        cntrlPressed
        occ

        ROIFields
        ROILabels
        numActiveFields

        chnlDlgBox
        bcDlgBox
        bidiShiftDlgBox
        thresholdDlgBox
        dmxDlgBox
        interruptDialog
    end
    
    properties (Access = public)
        v
    end
    
    methods (Access = public)
        function setROILabels(app,labelStrs)
            app.numActiveFields = length(labelStrs);
            for ii=1:app.numActiveFields
                app.ROILabels{ii}.Text = labelStrs{ii};
            end

            if (length(app.ROILabels)>length(app.numActiveFields))
                for ii=(app.numActiveFields+1):length(app.ROILabels)
                    app.ROILabels{ii}.Text = 'NA:';
                    app.ROIFields{ii}.Value = 0;
                    app.ROIFields{ii}.Enable = false;
                end
            end
        end

        function enableROIFields(app,enable)
            if (enable)
                for ii=1:app.numActiveFields
                    app.ROIFields{ii}.Enable = true;
                end
            else
                for ii=1:app.numActiveFields
                    app.ROIFields{ii}.Enable = false;
                    app.ROIFields{ii}.Value = 0;
                end
            end
            app.ROIDeleteButton.Enable = enable;
        end

        function setROIFields(app,vals)
            for ii=1:length(vals)
                app.ROIFields{ii}.Value = vals(ii);
            end
        end

        function selectRefImage(app)
            if (app.bidirectional)
                if (app.frameParity)
                    app.shiftImage = circshift(app.dmxImages(:,:,app.slice,2*app.channel-1),app.yShift,1);
                else
                    app.shiftImage = app.dmxImages(:,:,app.slice,2*app.channel);
                end
            else
                app.shiftImage = app.dmxImages(:,:,app.slice,app.channel);
            end

            if (app.parityFlag)
                app.parityFlag = false;
            else
                if (~isempty(app.thresholdDlgBox))
                    app.thresholdDlgBox.reinit();
                end
            end
            app.chngImgBnds(app.BCwndw(app.channel,:));

            if (~isempty(app.bcDlgBox))
                app.bcDlgBox.reinit(app.shiftImage,app.BCwndw(app.channel,:));
            end
        end

        function updateDisplay(app)
            app.borderMask = app.threshMaskHandler.fullMask - app.threshMaskHandler.baseMask + app.threshMaskHandler.excludedMask;
            app.dispImage(:,:,1) = app.contrImage .* (1 - app.threshMaskHandler.fullMask) + app.threshMaskHandler.fullMask .* (app.op + app.contrImage * (1-app.op));
            app.dispImage(:,:,2) = app.contrImage .* (1 - app.borderMask) + app.borderMask .* (app.op + app.contrImage * (1-app.op));
            app.dispImage(:,:,3) = app.contrImage .* (1 - app.shapeMaskHandler.getMask()) + app.shapeMaskHandler.getMask() .* (app.op + app.contrImage * (1-app.op));
            app.dispImage(:,:,3) = app.dispImage(:,:,3) .* (1-app.op2*app.threshMaskHandler.selectedMask) + app.threshMaskHandler.selectedMask .* (app.op2 + app.dispImage(:,:,3) * (1-app.op2));
            app.imhandle.CData = app.dispImage;
        end

        function ImgClk(app,~,evt)
            coord = ceil(evt.IntersectionPoint);
            x = coord(1);
            y = coord(2);
            app.shapeMaskHandler.deselectROI();
            app.enableROIFields(false);
            app.ROIDeleteButton.Enable = app.threshMaskHandler.imgClk(x,y,app.cntrlPressed);
            app.updateDisplay();
        end

        function deselectThresh(app)
            app.threshMaskHandler.deselectROI();
        end

        function updateOccLabels(app)
            if (app.sepChannels)
                app.OccLabel.Text = strcat(num2str(round(1000*app.occ(app.slice,app.channel))/10),"%");
                app.OccChLabel.Text = strcat(num2str(round(1000*mean(app.occ(:,app.channel)))/10),"%");
                app.OccTotLabel.Text = strcat(num2str(round(1000*mean(app.occ,'all'))/10),"%");
            else
                app.OccLabel.Text = strcat(num2str(round(1000*app.occ(app.slice,1))/10),"%");
                app.OccChLabel.Text = strcat(num2str(round(1000*mean(app.occ(:,1)))/10),"%");
                app.OccTotLabel.Text = app.OccChLabel.Text;
            end
        end

        function calcOcc(app,sl,ch)
            if (app.bidirectional)
                app.occ(sl,ch) = sum(((circshift(app.threshMaskHandler.getFullMask(sl,ch),1,app.yShift)+app.shapeMaskHandler.getChMask(sl,ch))>0),'all')/app.numPx;
            else
                app.occ(sl,ch) = sum(((app.threshMaskHandler.getFullMask(sl,ch)+app.shapeMaskHandler.getChMask(sl,ch))>0),'all')/app.numPx;
            end
        end

        function updateOcc(app)
            if (app.sepChannels)
                app.calcOcc(app.slice,app.channel);
            else
                app.calcOcc(app.slice,1);
            end
            app.updateOccLabels();
        end

        function updateChOcc(app)
            if (app.sepChannels)
                for sl=1:app.slices
                    app.calcOcc(sl,app.channel);
                end
            else
                for sl=1:app.slices
                    app.calcOcc(sl,1);
                end
            end
            app.updateOccLabels();
        end

        function updateTotOcc(app)
            if (app.sepChannels)
                for sl=1:app.slices
                    for ch=1:app.channels
                        app.calcOcc(sl,ch);
                    end
                end
            else
                for sl=1:app.slices
                    app.calcOcc(sl,1);
                end
            end
            app.updateOccLabels();
        end

        %% Helper App calback functions
        function chnlClbk(app, params, limit)
            delete(app.chnlDlgBox);
            app.chnlDlgBox = [];
            drawnow;
            if ((app.v(1)>9)||((app.v(1)==9)&&(app.v(2)>=12)))
                focus(app.UIFigure);
            else
                figure(app.UIFigure);
            end
            if (isempty(params))
                close(app.interruptDialog);
                app.interruptDialog = [];
                return;
            end

            app.interruptDialog.Title = "Opening file...";
            if ((app.v(1) < 9) || ((app.v(1)==9) && (app.v(2)<14)))
                disp('Matlab versions before 2023 cannot read large Tiff stacks. Limiting frames averaged')
                temp = 64000/(params.channels*params.slices*(1+params.bidirectional));
                if (limit<1)
                    limit = temp;
                else
                    limit = min(limit,temp);
                end
            end

            app.interruptDialog.Title = "Loading image...";
            try
                stackManager = StackTracker(params);
                tempImages = stackManager.buildProjection(fullfile(app.tempPath,app.tempFile),limit,app.interruptDialog);
            catch exception
                close(app.interruptDialog);
                app.interruptDialog = [];
                rethrow(exception);
            end

            app.file = app.tempFile;
            app.path = app.tempPath;
            app.bidirectional = params.bidirectional;
            app.channels = params.channels;
            app.slices = params.slices;
            app.sepChannels = params.splitChannels;

            % update ui elements for changing slice/channel
            app.frameParity = app.bidirectional;
            app.FrameParityButton.Enable = app.bidirectional;
            app.FrameParityButton.Text = "Odd";
            app.FrameParityButton.Value = true;
            app.channel = 1;
            app.ChannelSpinner.Enable = app.channels > 1;
            app.ChannelSpinner.Value = 1;
            if (app.channels>1)
                app.ChannelSpinner.Limits = [1,app.channels];
            else
                app.ChannelSpinner.Limits = [1,1.1];
            end
            app.slice = 1;
            app.SliceSpinner.Enable = app.slices > 1;
            app.SliceSpinner.Value = 1;
            if (app.slices>1)
                app.SliceSpinner.Limits = [1,app.slices];
            else
                app.SliceSpinner.Limits = [1,1.1];
            end

            % setup reference images
            app.isVolume = params.volume;
            app.BCwndw = zeros(app.channels,2);
            if (app.isVolume)
                app.refImages = zeros(size(tempImages));
                for sl=1:app.slices
                    for ch=1:app.channels
                        tempIm = tempImages(:,:,sl,(1+app.bidirectional)*ch-app.bidirectional);
                        maxVal = max(tempIm,[],'all');
                        minVal = min(tempIm,[],'all');
                        if (app.BCwndw(ch,1)>minVal)
                            app.BCwndw(ch,1) = minVal;
                        end

                        if (app.BCwndw(ch,2)<maxVal)
                            app.BCwndw(ch,2) = maxVal;
                        end
                        app.refImages(:,:,sl,(1+app.bidirectional)*ch-app.bidirectional) = tempIm-minVal;
                        if (app.bidirectional)
                            app.refImages(:,:,sl,2*ch) = tempImages(:,:,sl,2*ch)-minVal;
                        end
                    end
                end
            else
                app.refImages = zeros(size(tempImages,1),size(tempImages,2),1,size(tempImages,3));
                for ch=1:app.channels
                    tempIm = tempImages(:,:,(1+app.bidirectional)*ch-app.bidirectional);
                    app.BCwndw(ch,2) = max(tempIm,[],'all');
                    app.BCwndw(ch,1) = min(tempIm,[],'all');
                    app.refImages(:,:,1,(1+app.bidirectional)*ch-app.bidirectional) = tempIm - app.BCwndw(ch,1);
                    if (app.bidirectional)
                        app.refImages(:,:,1,2*ch) = tempImages(:,:,2*ch)-app.BCwndw(ch,1);
                    end
                end
                
                app.slices = 1;
            end
            params.dim = [size(app.refImages,1),size(app.refImages,2)];

            % calculate the autoY shift
            if (app.bidirectional)
                app.autoYShiftVal = 0;
                for ch=1:app.channels
                    for sl=1:app.slices
                        temp = xcorr2((app.refImages(:,:,sl,(2*ch-1))-mean(app.refImages(:,:,sl,(2*ch-1)),'all')),(app.refImages(:,:,sl,(2*ch))-mean(app.refImages(:,:,sl,(2*ch)),'all')));
                        temp = temp(:,size(app.refImages,2));
                        [~,ind] = max(temp);
                        app.autoYShiftVal = app.autoYShiftVal + size(app.refImages,1)-ind;
                    end
                end
                app.autoYShiftVal = app.autoYShiftVal/(app.channels*app.slices);
                app.yShift = app.autoYShiftVal;
                params.bidiShift = app.yShift;
            else
                app.autoYShiftVal = 0;
                app.yShift=0;
            end

            app.W = eye(app.channels);
            app.dmxImages = app.refImages;
            app.dmxBuff = zeros(size(app.refImages,1),size(app.refImages,2),app.channels);
            
            % start new shape mask handler
            if (~isempty(app.shapeMaskHandler))
                app.shapeMaskHandler.delete();
                app.shapeMaskHandler = [];
            end
            app.htot = stackManager.htot;
            app.shapeMaskHandler = MaskListenerHandler(app,app.imagePlot,params);

            % start new threshold mask handler
            if (~isempty(app.threshMaskHandler))
                app.threshMaskHandler = [];
            end
            app.threshMaskHandler = ThresholdMaskHandler(app,params,app.refImages);

            app.occ = zeros(app.slices,app.channels);
            app.OccLabel.Text = "0%";
            app.OccChLabel.Text = "0%";
            app.OccTotLabel.Text = "0%";
            app.numPx = size(app.refImages,1)*size(app.refImages,2);
            
            % reset image and occupancy plots to match current image
            % dimensions
            app.dispImage = zeros(size(app.refImages,1),size(app.refImages,2),3);
            app.imagePlot.Title.String = strcat("file: ", app.file);
            app.imhandle = image(app.imagePlot, app.dispImage,'ButtonDownFcn',@app.ImgClk);
            set(app.imagePlot,'xlim',[0,size(app.refImages,2)]);
            set(app.imagePlot,'ylim',[0,size(app.refImages,1)]);

            % enable ui elements
            app.LoadROIsMenu.Enable = true;
            app.SaveROIsMenu.Enable = true;
            app.ExportAESMaskMenu.Enable = true;
            app.ExportROIMasksMenu.Enable = true;
            app.WindowLevelMenu.Enable = true;
            app.BidirectionalShiftMenu.Enable = true;
            app.ROIShapeDropDown.Enable = true;
            app.ROIDeleteButton.Enable = false;
            app.ROIAddButton.Enable = true;
            app.DemixCrosstalkMenu.Enable = app.channels>1;
            app.ThresholdButton.Enable = true;
            app.BlockSliceOverlapCheckBox.Enable = true;
            app.BlockSliceOverlapCheckBox.Value = false;
            app.ApplytoAllSlicesCheckBox.Enable = true;
            app.ApplytoAllSlicesCheckBox.Value = false;
            app.BorderSpinner.Enable = true;
            app.BorderSpinner.Value = 0;
            app.MaxHoleSpinner.Enable = true;
            app.MaxHoleSpinner.Value = 1;

            % finish up
            app.parityFlag = false;
            app.selectRefImage();
            close(app.interruptDialog);
            app.interruptDialog = [];
        end

        % Window level helper app callbacks
        function closeBC(app)
            if (~isempty(app.bcDlgBox))
                delete(app.bcDlgBox);
                app.bcDlgBox = [];
            end
        end
            
        function chngImgBnds(app,bounds)
            app.BCwndw(app.channel,:) = bounds;
            tempMaskLow = app.shiftImage > bounds(1);
            tempMaskHigh = app.shiftImage > bounds(2);
            app.contrImage = tempMaskHigh + (tempMaskLow .* (1 - tempMaskHigh)) .* (app.shiftImage - bounds(1)) / (bounds(2) - bounds(1));
            app.updateDisplay();
        end

        % Bidirectional shift helper app callbacks
        function closeShift(app)
            if (~isempty(app.bidiShiftDlgBox))
                delete(app.bidiShiftDlgBox);
                app.bidiShiftDlgBox = [];
            end
        end

        function shift = autoYShift(app)
            if (app.bidirectional)
                shift = app.autoYShiftVal;
                app.yShift = shift;
                app.threshMaskHandler.setYShift(shift);
                app.selectRefImage();
            end
        end

        function chngYShift(app, shift)
            app.yShift = shift;
            app.threshMaskHandler.setYShift(shift);
            app.selectRefImage();
        end

        function closeDmx(app)
            if (~isempty(app.dmxDlgBox))
                delete(app.dmxDlgBox);
                app.dmxDlgBox = [];
            end
        end

        function setDmxFast(app,W)
            H = real(inv(W));
            for ch=1:app.channels
                app.dmxBuff(:,:,ch) = app.refImages(:,:,app.slice,ch*(1+app.bidirectional)-app.frameParity);
            end
            vec = reshape(H(app.channel,:),1,1,app.channels);
            app.dmxImages(:,:,app.slice,app.channel*(1+app.bidirectional)-app.frameParity) = sum(vec.*app.dmxBuff,3);
            app.parityFlag = true;
            app.selectRefImage();
        end

        function setDmx(app,W)
            app.W = W;
            H = real(inv(W));
            for sl=1:app.slices
                for par=0:(0+app.bidirectional)
                    for ch=1:app.channels
                        app.dmxBuff(:,:,ch) = app.refImages(:,:,sl,ch*(1+app.bidirectional)-par);
                    end

                    for ch=1:app.channels
                        vec = reshape(H(ch,:),1,1,app.channels);
                        app.dmxImages(:,:,sl,ch*(1+app.bidirectional)-par) = sum(vec.*app.dmxBuff,3);
                    end
                end
            end

            app.threshMaskHandler.setImages(app.dmxImages);
            app.selectRefImage();
        end

        % threshold mask helper functions
        function closeThreshold(app)
            if (~isempty(app.thresholdDlgBox))
                delete(app.thresholdDlgBox);
                app.thresholdDlgBox = [];
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupTasks(app)
            app.UIFigure.Visible = 'off';
            app.UIFigure.WindowState = 'normal';
            box(app.imagePlot,'on');
            disableDefaultInteractivity(app.imagePlot);
            app.imagePlot.Title.Interpreter = 'none';
            movegui(app.UIFigure,'center');
            daspect(app.imagePlot,[1,1,1]);
            temp = ver('Matlab'); %#ok<VERMATLAB>
            temp = split(temp.Version,'.');
            app.v = [str2double(temp{1}),str2double(temp{2})];

            app.ROIFields = cell(6,1);
            app.ROILabels = cell(6,1);
            app.ROIFields{1} = app.ROIField1;
            app.ROIFields{2} = app.ROIField2;
            app.ROIFields{3} = app.ROIField3;
            app.ROIFields{4} = app.ROIField4;
            app.ROIFields{5} = app.ROIField5;
            app.ROIFields{6} = app.ROIField6;
            app.ROILabels{1} = app.ROILabel1;
            app.ROILabels{2} = app.ROILabel2;
            app.ROILabels{3} = app.ROILabel3;
            app.ROILabels{4} = app.ROILabel4;
            app.ROILabels{5} = app.ROILabel5;
            app.ROILabels{6} = app.ROILabel6;
            app.ROIShapeDropDown.ItemsData = 1:6;
            app.UIFigure.Visible = 'on';
            app.cntrlPressed = false;
        end

        % Menu selected function: LoadImageMenu
        function loadImage(app, event)
            app.closeBC();
            app.closeShift();
            app.closeDmx();
            app.closeThreshold();

            if (~isempty(app.shapeMaskHandler))
                userInput = questdlg("All unsaved masks will be deleted. Continue?");
                if (~strcmp(userInput,'Yes'))
                    return;
                end
            end
            
            [app.tempFile, app.tempPath] = uigetfile('.tif'); 
            drawnow;
            if ((app.v(1)>9)||((app.v(1)==9)&&(app.v(2)>=12)))
                focus(app.UIFigure);
            else
                figure(app.UIFigure);
            end
            if (isequal(app.tempFile,0))
                return;
            end

            app.interruptDialog = uiprogressdlg(app.UIFigure,"Title","Waiting for channel info...");
            app.interruptDialog.Cancelable = "off";
            app.UIFigure.Pointer = 'watch';
            drawnow;
            app.chnlDlgBox = helper_apps.channel_helper_app(app);
            app.UIFigure.Pointer = 'arrow';
        end

        % Button pushed function: ROIAddButton
        function addROI(app, event)
            app.shapeMaskHandler.addROI(app.ROIShapeDropDown.Value);
            app.threshMaskHandler.deselectROI();
            app.updateDisplay();
        end

        % Button pushed function: ROIDeleteButton
        function deleteROI(app, event)
            app.shapeMaskHandler.deleteCurrentROI();
            app.threshMaskHandler.deleteROI();
            app.updateDisplay();
        end

        % Menu selected function: ExportAESMaskMenu
        function exportPattern(app, event)
            app.closeBC();
            app.closeShift();
            app.closeDmx();
            app.closeThreshold();

            filt = '*.tif';
            defName = split(app.file,'.');
            defName = defName{1};
            defName = fullfile(app.path,strcat(defName,'_mask.tif'));
            [outFile,outPath] = uiputfile(filt,'AES Mask',defName);
            drawnow;
            if ((app.v(1)>9)||((app.v(1)==9)&&(app.v(2)>=12)))
                focus(app.UIFigure);
            else
                figure(app.UIFigure);
            end
            if ((isequal(outFile,0)||isequal(outPath,0)))
                return
            end

            valid_output = false;
            opts.Interpreter = 'tex';
            opts.WindowStyle = 'modal';
                
            while(~valid_output)
                userInput = inputdlg({'x (px)','y (px)'},'Mask Displacement',[1,30],{'0','0'});
                if(isempty(userInput))
                    return;
                end

                xDisp = round(str2double(userInput{1}));
                yDisp = round(str2double(userInput{2}));

                if (isempty(xDisp) || isempty(yDisp) || abs(xDisp)>=size(app.shiftImage,2) || abs(yDisp) >= size(app.shiftImage,1))
                    waitfor(errordlg(strcat('X disp \in (',num2str(-size(app.shiftImage,2)),',',num2str(size(app.shiftImage,2)),'). Y disp \in (',num2str(-size(app.shiftImage,1)),',',num2str(size(app.shiftImage,1)),').'),'Bad input',opts));
                else
                    valid_output = true;
                end
            end

            if ((app.sepChannels && (app.channels > 1)) || (app.isVolume && app.slices > 1))
                userInput = questdlg("Include mask with combined channels?");
                if (strcmp(userInput,'Yes'))
                    combine = true;
                elseif (strcmp(userInput,'No'))
                    combine = false;
                else
                    return;
                end
            else
                combine = false;
            end
            app.UIFigure.Pointer = 'watch';
            drawnow;

            basename = split(outFile,'.');
            basename = basename{1};
            h = size(app.refImages,1);
            w = size(app.refImages,2);
            if (app.isVolume)
                app.htot = 2*h*app.slices;
                if (app.bidirectional)
                    if (app.sepChannels && (app.channels > 1))
                        combineMask = zeros(app.htot,w);
                        for ch=1:obj.channels
                            tempMask = zeros(app.htot,w);
                            for sl=1:app.slices
                                tempImage = (circshift(app.threshMaskHandler.getFullMask(sl,ch),app.yShift,1) + app.shapeMaskHandler.getChMask(sl,ch))>0;
                                tempMask(((sl-1)*h+1):(sl*h),:) = circshift(tempImage,[yDisp-app.yShift,xDisp]);
                                tempMask((app.htot-sl*h+1):(app.htot-(sl-1)*h),:) = circshift(flipud(tempImage),[yDisp,xDisp]);
                            end
                            combineMask = combineMask + tempMask;
                            imwrite((tempMask+0),fullfile(outPath,strcat(basename,'_ch',num2str(ch),'.tif')),'Compression','none');
                        end
                    else
                        tempMask = zeros(app.htot,w);
                        for sl=1:app.slices
                            tempImage = (circshift(app.threshMaskHandler.getFullMask(sl,1),app.yShift,1) + app.shapeMaskHandler.getChMask(sl,1))>0;
                            tempMask(((sl-1)*h+1):(sl*h),:) = circshift(tempImage,[yDisp-app.yShift,xDisp]);
                            tempMask((app.htot-sl*h+1):(app.htot-(sl-1)*h),:) = circshift(flipud(tempImage),[yDisp,xDisp]);
                        end
                        imwrite((tempMask+0),fullfile(outPath,strcat(basename,'.tif')),'Compression','none');
                    end
                else
                    if (app.sepChannels && (app.channels>1))
                        combineMask = zeros(app.htot,w);
                        for ch=1:app.channels
                            tempMask = zeros(app.htot,w);
                            for sl=1:app.slices
                                tempImage = (app.threshMaskHandler.getFullMask(sl,ch) + app.shapeMaskHandler.getChMask(sl,ch))>0;
                                tempMask(((sl-1)*h+1):(sl*h),:) = tempImage;
                                tempMask((app.htot-(sl*h)+1):(app.htot-(sl-1)*h),:) = tempImage;
                            end
                            tempMask = circshift(tempMask,[yDisp,xDisp]);
                            combineMask = combineMask + tempMask;
                            imwrite((tempMask+0),fullfile(outPath,strcat(basename,'_ch',num2str(ch),'.tif')),'Compression','none');
                        end
                    else
                        tempMask = zeros(app.htot,w);
                        for sl=1:app.slices
                            tempImage = (app.threshMaskHandler.getFullMask(sl,1) + app.shapeMaskHandler.getChMask(sl,1))>0;
                            tempMask(((sl-1)*h+1):(sl*h),:) = tempImage;
                            tempMask((app.htot-(sl*h)+1):(app.htot-(sl-1)*h),:) = tempImage;
                        end
                        tempMask = circshift(tempMask,[yDisp,xDisp]);
                        imwrite((tempMask+0),fullfile(outPath,strcat(basename,'.tif')),'Compression','none');
                    end
                end
            else
                if (app.bidirectional)
                    if (app.sepChannels && (app.channels > 1))
                        combineMask = zeros(app.htot,w);
                        for ch=1:app.channels
                            tempMask = zeros(app.htot,w);
                            tempImage = (circshift(app.threshMaskHandler.getFullMask(1,ch),app.yShift,1) + app.shapeMaskHandler.getChMask(1,ch))>0;
                            tempMask(1:h,:) = circshift(tempImage,[yDisp-app.yShift,xDisp]);
                            tempMask((app.htot-h+1):app.htot,:) = circshift(flipud(tempImage),[yDisp,xDisp]);
                            combineMask = combineMask + tempMask;
                            imwrite((tempMask+0),fullfile(outPath,strcat(basename,'_ch',num2str(ch),'.tif')),'Compression','none');
                        end
                    else
                        tempMask = zeros(app.htot,w);
                        tempImage = (circshift(app.threshMaskHandler.getFullMask(1,1),app.yShift,1) + app.shapeMaskHandler.getChMask(1,1))>0;
                        tempMask(1:h,:) = circshift(tempImage,[yDisp-app.yShift,xDisp]);
                        tempMask((app.htot-h+1):app.htot,:) = circshift(flipud(tempImage),[yDisp,xDisp]);
                        imwrite((tempMask+0),fullfile(outPath,strcat(basename,'.tif')),'Compression','none');
                    end
                else
                    if app.(app.sepChannels && (app.channels > 1))
                        combineMask = zeros(size(app.refImages,1),size(app.refImages,2));
                        for ch=1:app.channels
                            tempMask = (app.threshMaskHandler.getFullMask(1,ch) + app.shapeMaskHandler.getChMask(1,ch))>0;
                            tempMask = circshift(tempMask,[yDisp,xDisp]);
                            combineMask = combineMask + tempMask;
                            imwrite((tempMask+0),fullfile(outPath,strcat(basename,'_ch',num2str(ch),'.tif')),'Compression','none');
                        end
                    else
                        tempMask = (app.threshMaskHandler.getFullMask(1,1) + app.shapeMaskHandler.getChMask(1,1))>0;
                        tempMask = circshift(tempMask,[yDisp,xDisp]);
                        imwrite((tempMask+0),fullfile(outPath,strcat(basename,'.tif')),'Compression','none');
                    end
                end
            end

            if (combine)
                imwrite(((combineMask>0)+0),fullfile(outPath,strcat(basename,'_combined.tif')),'Compression','none');
            end

            app.path = outPath;
            app.UIFigure.Pointer = 'arrow';
        end

        % Menu selected function: SaveROIsMenu
        function saveROIs(app, event)
            filt = '*.mat';
            defName = fullfile(app.path,'roi.mat');
            [outFile,outPath] = uiputfile(filt,'ROIs',defName);
            if ((app.v(1)>9)||((app.v(1)==9)&&(app.v(2)>=12)))
                focus(app.UIFigure);
            else
                figure(app.UIFigure);
            end
            drawnow;
            if (isequal(outFile,0)||isequal(outPath,0))
                return;
            end

            app.UIFigure.Pointer = 'watch';
            drawnow;
            app.path = outPath;
            ff = fullfile(outPath,outFile);
            app.shapeMaskHandler.saveROIs(ff);
            app.threshMaskHandler.saveROIs(ff);
            dMix=app.W;
            save(ff,'dMix','-append');
            app.UIFigure.Pointer = 'arrow';
        end

        % Menu selected function: LoadROIsMenu
        function loadROIs(app, event)
            userInput = questdlg("All unsaved masks will be deleted. Continue?");
            if (~strcmp(userInput,'Yes'))
                return;
            end
            
            % queary file from user
            [tFile, tPath] = uigetfile('*.mat','Load ROI File',app.path);
            drawnow;
            if ((app.v(1)>9)||((app.v(1)==9)&&(app.v(2)>=12)))
                focus(app.UIFigure);
            else
                figure(app.UIFigure);
            end

            if (isequal(tFile,0) || isequal(tPath,0))
                return;
            end

            app.UIFigure.Pointer = 'watch';
            drawnow;
            params.channels = app.channels;
            params.volume = app.isVolume;
            params.slices = app.slices;
            params.splitChannels = app.sepChannels;
            params.bidirectional = app.bidirectional;
            params.dim = [size(app.refImages,1),size(app.refImages,2)];
            ff = fullfile(tPath,tFile);
            if (app.bidirectional)
                params.bidiShift = app.yShift;
            end
            
            try
                tempMaskHandler = MaskListenerHandler(app,app.imagePlot,params);
                data = load(ff);
                success = tempMaskHandler.loadROIs(data);
                if (~success)
                    tempMaskHandler.delete();
                    app.UIFigure.Pointer = 'arrow';
                    errordlg('Unable to read file','File Error');
                end
            catch exception
                app.UIFigure.Pointer = 'arrow';
                errordlg('Unable to read file','File Error');
                tempMaskHandler.delete();
                rethrow(exception);
            end
            
            if (success)
                app.shapeMaskHandler.delete();
                app.shapeMaskHandler = tempMaskHandler;
                app.shapeMaskHandler.setChannel(app.channel);
                app.shapeMaskHandler.setSlice(app.slice);
            end

            if (isfield(data,'dMix'))
                tempW = data.dMix;
                if (prod(size(tempW)==size(app.W)))
                    app.setDmx(tempW);
                end
            end
            app.threshMaskHandler.loadROIs(data);
            app.updateDisplay();
            app.UIFigure.Pointer = 'arrow';
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if (~isempty(app.chnlDlgBox))
                delete(app.chnlDlgBox);
            end
            app.closeBC();
            app.closeShift();
            app.closeDmx();
            app.closeThreshold();
            delete(app);
        end

        % Menu selected function: WindowLevelMenu
        function opnBC(app, event)
            if (isempty(app.bcDlgBox))
                app.UIFigure.Pointer = 'watch';
                drawnow;
                app.bcDlgBox = helper_apps.BC_helper_app(app,app.shiftImage,app.BCwndw(app.channel,:));
                app.UIFigure.Pointer = 'arrow';
            end
        end

        % Menu selected function: ExportROIMasksMenu
        function exportROIPatterns(app, event)
            app.closeBC();
            app.closeShift();
            app.closeDmx();
            app.closeThreshold();

            [outFile,outPath] = uiputfile('*.tif','AES Mask',fullfile(app.path,'roi.tif'));
            drawnow;
            if ((app.v(1)>9)||((app.v(1)==9)&&(app.v(2)>=12)))
                focus(app.UIFigure);
            else
                figure(app.UIFigure);
            end
            if ((isequal(outFile,0)||isequal(outPath,0)))
                return
            end

            basename = split(outFile,'.');
            basename = basename{1};
            app.UIFigure.Pointer = 'watch';
            drawnow;
            status = app.shapeMaskHandler.printROIMasks(basename,outPath);
            if (status)
                app.path = outPath;
                tempInd = app.shapeMaskHandler.numRois;
                app.threshMaskHandler.printROIMasks(basename,outPath,tempInd);
            else
                disp('Something went wrong.');
            end
            app.UIFigure.Pointer = 'arrow';
        end

        % Value changed function: ChannelSpinner
        function chngChannel(app, event)
            app.channel = app.ChannelSpinner.Value;
            app.shapeMaskHandler.setChannel(app.channel);
            app.threshMaskHandler.setChannel(app.channel);
            app.selectRefImage();
        end

        % Value changed function: ROIField1, ROIField2, ROIField3, 
        % ...and 3 other components
        function ROIFieldChng(app, event)
            src = event.Source;
            ind=0;
            for ii=1:length(app.ROIFields)
                if (app.ROIFields{ii}==src)
                    ind=ii;
                    break;
                end
            end
            
            if (ind~=0)
                val = src.Value;
                val = app.shapeMaskHandler.chngParam(val,ind);
                src.Value = val;
            end
            app.updateDisplay();
        end

        % Value changed function: FrameParityButton
        function chngFrmParity(app, event)
            app.frameParity = app.FrameParityButton.Value;
            if (app.frameParity)
                app.FrameParityButton.Text = 'Odd';
            else
                app.FrameParityButton.Text = 'Even';
            end
            app.parityFlag = true;
            app.selectRefImage();
            app.BorderSpinner.Value = app.threshMaskHandler.getBorder();
        end

        % Value changed function: SliceSpinner
        function chngSlice(app, event)
            app.slice = app.SliceSpinner.Value;
            app.shapeMaskHandler.setSlice(app.slice);
            app.threshMaskHandler.setSlice(app.slice);
            if (~isempty(app.dmxDlgBox))
                for ch=1:app.channels
                    app.dmxBuff(:,:,ch) = app.refImages(:,:,app.slice,(1+app.bidirectional)*ch-app.bidirectional);
                end
                app.dmxDlgBox.reinit(app.dmxBuff);
            end
            app.selectRefImage();
            app.BorderSpinner.Value = app.threshMaskHandler.getBorder();
        end

        % Menu selected function: BidirectionalShiftMenu
        function opnBidiShift(app, event)
            if (isempty(app.bidiShiftDlgBox))
                app.UIFigure.Pointer = 'watch';
                drawnow;
                app.bidiShiftDlgBox = helper_apps.bidirectional_shift_helper_app(app,app.yShift);
                app.UIFigure.Pointer = 'arrow';
            end
        end

        % Value changed function: ROIShapeDropDown
        function chngROIType(app, event)
            value = app.ROIShapeDropDown.Value;
            app.setROILabels(app.shapeMaskHandler.ROIParamLabels{value});
            for ii=1:app.numActiveFields
                app.ROIFields{ii}.Value = 0;
                app.ROIFields{ii}.Enable = false;
            end
            app.ROIDeleteButton.Enable = false;
            app.shapeMaskHandler.deselectROI();
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            modifier = event.Modifier;

            if (strcmp(key,'delete'))
                if (app.ROIDeleteButton.Enable)
                    app.deleteROI([]);
                end
            end

            app.cntrlPressed = false;
            shiftPressed = false;
            for ii=1:length(modifier)
                if (strcmp(modifier{ii},'control'))
                    app.cntrlPressed = true;
                end

                if (strcmp(modifier{ii},'shift'))
                    shiftPressed = true;
                end
            end

            if (~app.cntrlPressed)
                if (~isempty(app.shapeMaskHandler))
                    if (strcmp(key,'leftarrow'))
                            app.shapeMaskHandler.shiftMask([-1,0]);
                    elseif (strcmp(key,'rightarrow'))
                            app.shapeMaskHandler.shiftMask([1,0]);
                    elseif (strcmp(key,'uparrow'))
                            app.shapeMaskHandler.shiftMask([0,-1]);
                    elseif (strcmp(key,'downarrow'))
                            app.shapeMaskHandler.shiftMask([0,1]);
                    end
                end
                return;
            end

            if (shiftPressed)
                if (strcmp(key,'o'))
                    if (app.LoadROIsMenu.Enable)
                        app.loadROIs([]);
                        app.cntrlPressed = false;
                    end
                elseif (strcmp(key,'c'))
                    if (app.WindowLevelMenu.Enable)
                        app.opnBC([]);
                        app.cntrlPressed = false;
                    end
                elseif (strcmp(key,'p'))
                    if (app.ExportROIMasksMenu.Enable)
                        app.exportROIPatterns([]);
                        app.cntrlPressed = false;
                    end
                end
            else
                if (strcmp(key,'o'))
                    if (app.LoadImageMenu.Enable)
                        app.loadImage([]);
                        app.cntrlPressed = false;
                    end
                elseif (strcmp(key,'b'))
                    if (app.BidirectionalShiftMenu.Enable)
                        app.opnBidiShift([]);
                        app.cntrlPressed = false;
                    end
                elseif (strcmp(key,'s'))
                    if (app.SaveROIsMenu.Enable)
                        app.saveROIs([]);
                        app.cntrlPressed = false;
                    end
                elseif (strcmp(key,'p'))
                    if (app.ExportAESMaskMenu.Enable)
                        app.exportPattern([]);
                        app.cntrlPressed = false;
                    end
                elseif (strcmp(key,'n'))
                    if (app.ROIAddButton.Enable)
                        app.addROI([]);
                        app.cntrlPressed = false;
                    end
                elseif (strcmp(key,'d'))
                    if (app.DemixCrosstalkMenu.Enable)
                        app.opnDmx([]);
                        app.cntrlPressed = false;
                    end
                elseif (strcmp(key,'t'))
                    if (app.ThresholdButton.Enable)
                        app.opnThreshold();
                    end
                elseif (strcmp(key,'leftarrow'))
                    if (app.ROIShapeDropDown.Enable)
                        app.ROIShapeDropDown.Value = mod((app.ROIShapeDropDown.Value-2),6)+1;
                        app.chngROIType([]);
                        app.cntrlPressed = false;
                    end
                elseif (strcmp(key,'rightarrow'))
                    if (app.ROIShapeDropDown.Enable)
                        app.ROIShapeDropDown.Value = mod(app.ROIShapeDropDown.Value,6)+1;
                        app.chngROIType([]);
                        app.cntrlPressed = false;
                    end
                end
            end
        end

        % Menu selected function: DemixCrosstalkMenu
        function opnDmx(app, event)
            if (~isempty(app.dmxDlgBox))
                return;
            end

            app.UIFigure.Pointer = 'watch';
            drawnow;
            for ch=1:app.channels
                app.dmxBuff(:,:,ch) = app.refImages(:,:,app.slice,(1+app.bidirectional)*ch-app.bidirectional);
            end
            app.dmxDlgBox = helper_apps.crosstalk_demixer_helper_app(app,app.dmxBuff,app.W);
            app.UIFigure.Pointer = 'arrow';
        end

        % Button pushed function: ThresholdButton
        function opnThreshold(app, event)
            if (isempty(app.thresholdDlgBox))
                app.UIFigure.Pointer = 'watch';
                drawnow;
                app.thresholdDlgBox = helper_apps.mask_threshold_helper_app(app,app.threshMaskHandler);
                app.UIFigure.Pointer = 'arrow';
            end
        end

        % Value changed function: BorderSpinner
        function chngBorder(app, event)
            app.threshMaskHandler.setBorder(app.BorderSpinner.Value);
            app.updateDisplay();
        end

        % Value changed function: ApplytoAllSlicesCheckBox
        function applyToAllSlices(app, event)
            app.threshMaskHandler.setChngAllSlices(app.ApplytoAllSlicesCheckBox.Value);
            app.updateDisplay();
        end

        % Value changed function: BlockSliceOverlapCheckBox
        function blockSliceOverlap(app, event)
            app.threshMaskHandler.setBlockOverlap(app.BlockSliceOverlapCheckBox.Value);
            app.updateDisplay();
        end

        % Key release function: UIFigure
        function UIFigureKeyRelease(app, event)
            key = event.Key;
            if (strcmp(key,'control'))
                app.cntrlPressed = false;
            end
        end

        % Value changed function: MaxHoleSpinner
        function chngMaxHole(app, event)
            app.UIFigure.Pointer = 'watch';
            drawnow;
            app.threshMaskHandler.setMaxHole(app.MaxHoleSpinner.Value);
            app.updateDisplay();
            app.UIFigure.Pointer = 'arrow';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'AES mask app';
            app.UIFigure.Icon = fullfile(pathToMLAPP, '+helper_apps', 'images', 'thumbnail_mask_app.png');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIFigure.KeyReleaseFcn = createCallbackFcn(app, @UIFigureKeyRelease, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.LoadImageMenu = uimenu(app.FileMenu);
            app.LoadImageMenu.MenuSelectedFcn = createCallbackFcn(app, @loadImage, true);
            app.LoadImageMenu.Tooltip = {'(Cntl+O)'};
            app.LoadImageMenu.Text = 'Load Image';
            app.LoadROIsMenu = uimenu(app.FileMenu);
            app.LoadROIsMenu.MenuSelectedFcn = createCallbackFcn(app, @loadROIs, true);
            app.LoadROIsMenu.Tooltip = {'(Cntl+Shift+O)'};
            app.LoadROIsMenu.Enable = 'off';
            app.LoadROIsMenu.Text = 'Load ROIs';
            app.SaveROIsMenu = uimenu(app.FileMenu);
            app.SaveROIsMenu.MenuSelectedFcn = createCallbackFcn(app, @saveROIs, true);
            app.SaveROIsMenu.Tooltip = {'(Cntl+S)'};
            app.SaveROIsMenu.Enable = 'off';
            app.SaveROIsMenu.Text = 'Save ROIs';
            app.ExportAESMaskMenu = uimenu(app.FileMenu);
            app.ExportAESMaskMenu.MenuSelectedFcn = createCallbackFcn(app, @exportPattern, true);
            app.ExportAESMaskMenu.Tooltip = {'(Cntl+P)'};
            app.ExportAESMaskMenu.Enable = 'off';
            app.ExportAESMaskMenu.Text = 'Export AES Mask';
            app.ExportROIMasksMenu = uimenu(app.FileMenu);
            app.ExportROIMasksMenu.MenuSelectedFcn = createCallbackFcn(app, @exportROIPatterns, true);
            app.ExportROIMasksMenu.Tooltip = {'(Cntl+Shift+P)'};
            app.ExportROIMasksMenu.Enable = 'off';
            app.ExportROIMasksMenu.Text = 'Export ROI Masks';
            app.ImageMenu = uimenu(app.UIFigure);
            app.ImageMenu.Text = 'Image';
            app.WindowLevelMenu = uimenu(app.ImageMenu);
            app.WindowLevelMenu.MenuSelectedFcn = createCallbackFcn(app, @opnBC, true);
            app.WindowLevelMenu.Tooltip = {'(Cntl+Shift+C)'};
            app.WindowLevelMenu.Enable = 'off';
            app.WindowLevelMenu.Text = 'Window/Level';
            app.BidirectionalShiftMenu = uimenu(app.ImageMenu);
            app.BidirectionalShiftMenu.MenuSelectedFcn = createCallbackFcn(app, @opnBidiShift, true);
            app.BidirectionalShiftMenu.Tooltip = {'(Cntl+B)'};
            app.BidirectionalShiftMenu.Enable = 'off';
            app.BidirectionalShiftMenu.Text = 'Bidirectional Shift';
            app.DemixCrosstalkMenu = uimenu(app.ImageMenu);
            app.DemixCrosstalkMenu.MenuSelectedFcn = createCallbackFcn(app, @opnDmx, true);
            app.DemixCrosstalkMenu.Tooltip = {'(Cntl+D)'};
            app.DemixCrosstalkMenu.Enable = 'off';
            app.DemixCrosstalkMenu.Text = 'Demix Crosstalk';
            app.GridLayout3 = uigridlayout(app.UIFigure);
            app.ImagePanel = uipanel(app.GridLayout3);
            app.ImagePanel.Title = 'Image';
            app.GridLayout2 = uigridlayout(app.ImagePanel);
            app.imagePlot = uiaxes(app.GridLayout2);
            title(app.imagePlot, 'No File Selected')
            xlabel(app.imagePlot, 'X')
            ylabel(app.imagePlot, 'Y')
            zlabel(app.imagePlot, 'Z')
            app.OccupancyPanel = uipanel(app.GridLayout2);
            app.OccupancyPanel.Title = 'Occupancy';
            app.GridLayout8 = uigridlayout(app.OccupancyPanel);
            app.ImageLabel = uilabel(app.GridLayout8);
            app.ImageLabel.Text = 'Image:';
            app.OccLabel = uilabel(app.GridLayout8);
            app.OccLabel.Text = '0%';
            app.ChannelLabel = uilabel(app.GridLayout8);
            app.ChannelLabel.Text = 'Channel:';
            app.OccChLabel = uilabel(app.GridLayout8);
            app.OccChLabel.Text = '0%';
            app.TotalLabel = uilabel(app.GridLayout8);
            app.TotalLabel.Text = 'Total:';
            app.OccTotLabel = uilabel(app.GridLayout8);
            app.OccTotLabel.Text = '0%';
            app.StackNavigationPanel = uipanel(app.GridLayout2);
            app.StackNavigationPanel.Title = 'Stack Navigation';
            app.GridLayout7 = uigridlayout(app.StackNavigationPanel);
            app.SliceSpinnerLabel = uilabel(app.GridLayout7);
            app.SliceSpinnerLabel.Text = 'Slice:';
            app.SliceSpinner = uispinner(app.GridLayout7);
            app.SliceSpinner.Limits = [1 1.1];
            app.SliceSpinner.RoundFractionalValues = 'on';
            app.SliceSpinner.ValueChangedFcn = createCallbackFcn(app, @chngSlice, true);
            app.SliceSpinner.Enable = 'off';
            app.SliceSpinner.Value = 1;
            app.ChannelSpinnerLabel = uilabel(app.GridLayout7);
            app.ChannelSpinnerLabel.Text = 'Channel:';
            app.ChannelSpinner = uispinner(app.GridLayout7);
            app.ChannelSpinner.Limits = [1 1.1];
            app.ChannelSpinner.RoundFractionalValues = 'on';
            app.ChannelSpinner.ValueChangedFcn = createCallbackFcn(app, @chngChannel, true);
            app.ChannelSpinner.Editable = 'off';
            app.ChannelSpinner.Enable = 'off';
            app.ChannelSpinner.Value = 1;
            app.FrameParityLabel = uilabel(app.GridLayout7);
            app.FrameParityLabel.Text = 'Frame Parity:';
            app.FrameParityButton = uibutton(app.GridLayout7, 'state');
            app.FrameParityButton.ValueChangedFcn = createCallbackFcn(app, @chngFrmParity, true);
            app.FrameParityButton.Enable = 'off';
            app.FrameParityButton.Text = 'Odd';
            app.FrameParityButton.Value = true;
            app.ROIPanel = uipanel(app.GridLayout3);
            app.ROIPanel.Title = 'ROI';
            app.GridLayout9 = uigridlayout(app.ROIPanel);
            app.ShapesPanel = uipanel(app.GridLayout9);
            app.ShapesPanel.Title = 'Shapes';
            app.GridLayout6 = uigridlayout(app.ShapesPanel);
            app.ROIAddButton = uibutton(app.GridLayout6, 'push');
            app.ROIAddButton.ButtonPushedFcn = createCallbackFcn(app, @addROI, true);
            app.ROIAddButton.Enable = 'off';
            app.ROIAddButton.Tooltip = {'(Cntl+N)'};
            app.ROIAddButton.Text = 'Add';
            app.ROIShapeDropDownLabel = uilabel(app.GridLayout6);
            app.ROIShapeDropDownLabel.Text = 'ROI Shape';
            app.ROIShapeDropDown = uidropdown(app.GridLayout6);
            app.ROIShapeDropDown.Items = {'Rectangle', 'Circle', 'Ellipse', 'Ring', 'Poly Line', 'Polygon'};
            app.ROIShapeDropDown.ValueChangedFcn = createCallbackFcn(app, @chngROIType, true);
            app.ROIShapeDropDown.Enable = 'off';
            app.ROIShapeDropDown.Tooltip = {'(Cntl+left/right)'};
            app.ROIShapeDropDown.Value = 'Rectangle';
            app.ROILabel1 = uilabel(app.GridLayout6);
            app.ROILabel1.Text = 'NA:';
            app.ROIField1 = uieditfield(app.GridLayout6, 'numeric');
            app.ROIField1.ValueChangedFcn = createCallbackFcn(app, @ROIFieldChng, true);
            app.ROIField1.Enable = 'off';
            app.ROILabel2 = uilabel(app.GridLayout6);
            app.ROILabel2.Text = 'NA:';
            app.ROIField2 = uieditfield(app.GridLayout6, 'numeric');
            app.ROIField2.ValueChangedFcn = createCallbackFcn(app, @ROIFieldChng, true);
            app.ROIField2.Enable = 'off';
            app.ROILabel3 = uilabel(app.GridLayout6);
            app.ROILabel3.Text = 'NA:';
            app.ROIField3 = uieditfield(app.GridLayout6, 'numeric');
            app.ROIField3.ValueChangedFcn = createCallbackFcn(app, @ROIFieldChng, true);
            app.ROIField3.Enable = 'off';
            app.ROILabel4 = uilabel(app.GridLayout6);
            app.ROILabel4.Text = 'NA:';
            app.ROIField4 = uieditfield(app.GridLayout6, 'numeric');
            app.ROIField4.ValueChangedFcn = createCallbackFcn(app, @ROIFieldChng, true);
            app.ROIField4.Enable = 'off';
            app.ROILabel5 = uilabel(app.GridLayout6);
            app.ROILabel5.Text = 'NA:';
            app.ROIField5 = uieditfield(app.GridLayout6, 'numeric');
            app.ROIField5.ValueChangedFcn = createCallbackFcn(app, @ROIFieldChng, true);
            app.ROIField5.Enable = 'off';
            app.ROILabel6 = uilabel(app.GridLayout6);
            app.ROILabel6.Text = 'NA:';
            app.ROIField6 = uieditfield(app.GridLayout6, 'numeric');
            app.ROIField6.ValueChangedFcn = createCallbackFcn(app, @ROIFieldChng, true);
            app.ROIField6.Enable = 'off';
            app.ThresholdMaskPanel = uipanel(app.GridLayout9);
            app.ThresholdMaskPanel.Title = 'Threshold Mask';
            app.GridLayout10 = uigridlayout(app.ThresholdMaskPanel);
            app.BorderSpinnerLabel = uilabel(app.GridLayout10);
            app.BorderSpinnerLabel.Text = 'Border:';
            app.BorderSpinner = uispinner(app.GridLayout10);
            app.BorderSpinner.Limits = [0 Inf];
            app.BorderSpinner.RoundFractionalValues = 'on';
            app.BorderSpinner.ValueChangedFcn = createCallbackFcn(app, @chngBorder, true);
            app.BorderSpinner.Enable = 'off';
            app.ApplytoAllSlicesCheckBox = uicheckbox(app.GridLayout10);
            app.ApplytoAllSlicesCheckBox.ValueChangedFcn = createCallbackFcn(app, @applyToAllSlices, true);
            app.ApplytoAllSlicesCheckBox.Enable = 'off';
            app.ApplytoAllSlicesCheckBox.Text = 'Apply to All Slices';
            app.BlockSliceOverlapCheckBox = uicheckbox(app.GridLayout10);
            app.BlockSliceOverlapCheckBox.ValueChangedFcn = createCallbackFcn(app, @blockSliceOverlap, true);
            app.BlockSliceOverlapCheckBox.Enable = 'off';
            app.BlockSliceOverlapCheckBox.Text = 'Block Slice Overlap';
            app.ThresholdButton = uibutton(app.GridLayout10, 'push');
            app.ThresholdButton.ButtonPushedFcn = createCallbackFcn(app, @opnThreshold, true);
            app.ThresholdButton.Enable = 'off';
            app.ThresholdButton.Tooltip = {'(Cntl+T)'};
            app.ThresholdButton.Text = 'Threshold';
            app.MaxHoleSpinnerLabel = uilabel(app.GridLayout10);
            app.MaxHoleSpinnerLabel.Text = 'Max Hole:';
            app.MaxHoleSpinner = uispinner(app.GridLayout10);
            app.MaxHoleSpinner.Limits = [1 Inf];
            app.MaxHoleSpinner.RoundFractionalValues = 'on';
            app.MaxHoleSpinner.ValueChangedFcn = createCallbackFcn(app, @chngMaxHole, true);
            app.MaxHoleSpinner.Enable = 'off';
            app.MaxHoleSpinner.Value = 1;
            app.ROIDeleteButton = uibutton(app.GridLayout9, 'push');
            app.ROIDeleteButton.ButtonPushedFcn = createCallbackFcn(app, @deleteROI, true);
            app.ROIDeleteButton.Enable = 'off';
            app.ROIDeleteButton.Tooltip = {'(Del)'};
            app.ROIDeleteButton.Text = 'Delete ROI';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = aes_image_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupTasks)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end