classdef mtn_output_helper_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        PreviewButton                   matlab.ui.control.Button
        CancelButton                    matlab.ui.control.Button
        ProcessButton                   matlab.ui.control.Button
        TraceExtractionPanel            matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        ZeroTracesCheckBox              matlab.ui.control.CheckBox
        OverlapCheckBox                 matlab.ui.control.CheckBox
        SaveMtnCheckBox                 matlab.ui.control.CheckBox
        BgMeanCheckBox                  matlab.ui.control.CheckBox
        AesRoiCheckBox                  matlab.ui.control.CheckBox
        SmplRoiCheckBox                 matlab.ui.control.CheckBox
        VideoOutputPanel                matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        SaveVidCheckBox                 matlab.ui.control.CheckBox
        SubtractBgCheckBox              matlab.ui.control.CheckBox
        CompressCheckBox                matlab.ui.control.CheckBox
        MotionCorrectionPanel           matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        InitialPassesEditField          matlab.ui.control.NumericEditField
        InitialWindowPassesEditFieldLabel  matlab.ui.control.Label
        FFTCheckBox                     matlab.ui.control.CheckBox
        SpatialBlurEditField            matlab.ui.control.NumericEditField
        SpatialBlurEditFieldLabel       matlab.ui.control.Label
        PassesEditField                 matlab.ui.control.NumericEditField
        CorrectionPassesEditFieldLabel  matlab.ui.control.Label
        MainChannelEditField            matlab.ui.control.NumericEditField
        MainChannelLabel                matlab.ui.control.Label
        CorrectMtnCheckBox              matlab.ui.control.CheckBox
        MtnWindowEditField              matlab.ui.control.NumericEditField
        TemporalsmoothingwindowEditFieldLabel  matlab.ui.control.Label
        IntlWindowEditField             matlab.ui.control.NumericEditField
        InitialtemplatewindowEditFieldLabel  matlab.ui.control.Label
        OtherPanel                      matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        SaveMasksCheckBox               matlab.ui.control.CheckBox
        ProjectionCheckBox              matlab.ui.control.CheckBox
        MultiFileCheckBox               matlab.ui.control.CheckBox
    end

    %%%%%%%%%%%%%%%
    % helper window for selecting file processing and output settings
    %%%%%%%%%%%%%%%

    properties (Access = private)
        callingApp
        params
        canOverlap

        active
        multiChannel
    end

    properties (GetAccess = public)
        interruptDialog
    end
    
    methods (Access = private)
        
        % builds params struct from user selection
        % 'params' is struct used store the information needed when
        % processing or motion correcting a video
        function updateParams(app)
            app.params.multiVid = app.MultiFileCheckBox.Value;
            app.params.mtn = app.CorrectMtnCheckBox.Value;
            app.params.saveVid = app.SaveVidCheckBox.Value;
            app.params.zeroTrace = app.ZeroTracesCheckBox.Value&&app.ZeroTracesCheckBox.Enable;
            app.params.mtnIntlWndw = app.IntlWindowEditField.Value;
            app.params.mtnWndw = app.MtnWindowEditField.Value;
            app.params.compress = app.CompressCheckBox.Value&&app.CompressCheckBox.Enable;
            app.params.saveMasks = app.SaveMasksCheckBox.Value;
            app.params.zeroVid = app.SubtractBgCheckBox.Value&&app.SubtractBgCheckBox.Enable;
            app.params.bgTrace = app.BgMeanCheckBox.Value;
            app.params.aesTrace = app.AesRoiCheckBox.Value;
            app.params.smplTrace = app.SmplRoiCheckBox.Value;
            app.params.mtnTrace = app.SaveMtnCheckBox.Value;
            app.params.mtnOverlap = app.OverlapCheckBox.Value;
            app.params.mainChannel = app.MainChannelEditField.Value;
            app.params.sptlWndw = app.SpatialBlurEditField.Value;
            if (app.params.mtn)
                app.params.passes = app.PassesEditField.Value;
                app.params.intlPasses = app.InitialPassesEditField.Value;
            else
                app.params.passes = 1;
                app.params.intlPasses = 1;
            end

            app.params.proj = app.ProjectionCheckBox.Value;
            app.params.fft = app.FFTCheckBox.Value;
        end
    end

    methods (Access = public)
        % 'active' is a boolean determining if a progress dialog window is
        % used to prevent user interaction
        function setActive(app, active)
            if (app.active)
                if (~active)
                    app.interruptDialog = uiprogressdlg(app.UIFigure,"Title","Busy...");
                end
            else
                if (active)
                    close(app.interruptDialog);
                    app.interruptDialog = [];
                end
            end

            app.active = active;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp, params)
            % set usable fields using partial information available in
            % 'params' struct
            app.UIFigure.Visible = 'off';
            app.UIFigure.WindowState = 'normal';
            app.callingApp = callingApp;
            app.params = validateParams(params);

            app.MainChannelEditField.Value = app.params.mainChannel;
            reslice = app.params.volume && (~isempty(app.params.reslicer)) && app.params.reslicer.mapActive;
            if (reslice)
                app.multiChannel = app.params.reslicer.channelsOut>1;
                if (app.multiChannel)
                    app.MainChannelEditField.Limits = [1,app.params.reslicer.channelsOut];
                end
            else
                app.multiChannel = app.params.channels>1;
                if (app.multiChannel)
                    app.MainChannelEditField.Limits = [1,app.params.channels];
                end
            end

            temp = (sum(app.params.numRoiAes,'all')>0);
            app.AesRoiCheckBox.Enable = temp;
            app.CorrectMtnCheckBox.Enable = temp;
            app.SmplRoiCheckBox.Enable = (sum(app.params.numRoiSmpl,'all')>0);
            app.canOverlap = sum(app.params.numRoiAes.*app.params.numRoiSmpl,'all')>0;
            app.MultiFileCheckBox.Enable = app.params.multiVid;

            app.active = true;
            helper_apps.centerApp(app,callingApp);
            app.UIFigure.Visible = 'on';
        end

        % Value changed function: CorrectMtnCheckBox
        function correctMtn(app, event)
            % makes sure relavent settings are available only if you plan
            % on motion correcting
            app.MtnWindowEditField.Enable = app.CorrectMtnCheckBox.Value;
            app.IntlWindowEditField.Enable = app.CorrectMtnCheckBox.Value;
            app.SaveMtnCheckBox.Enable = app.CorrectMtnCheckBox.Value;
            app.MainChannelEditField.Enable = (app.CorrectMtnCheckBox.Value && app.multiChannel);
            app.SpatialBlurEditField.Enable = app.CorrectMtnCheckBox.Value;
            app.FFTCheckBox.Enable = app.CorrectMtnCheckBox.Value;
            app.PassesEditField.Enable = app.CorrectMtnCheckBox.Value;
            app.InitialPassesEditField.Enable = app.CorrectMtnCheckBox.Value;
            if (app.SmplRoiCheckBox.Enable)
                app.OverlapCheckBox.Enable = (app.CorrectMtnCheckBox.Value && app.canOverlap);
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.callingApp.closeOutputDlg();
            delete(app);
        end

        % Button pushed function: ProcessButton
        function process(app, event)
            app.updateParams();
            app.callingApp.processVideos(app.params);
        end

        % Button pushed function: CancelButton
        function cancel(app, event)
            app.callingApp.closeOutputDlg();
            delete(app);
        end

        % Value changed function: MtnWindowEditField
        function chkSmthVal(app, event)
            % makes sure motion smoothing window is odd (otherwise you have
            % amibguity in weighting of current frame)
            value = app.MtnWindowEditField.Value;
            if (mod(value,2)==0)
                app.MtnWindowEditField.Value = value + 1;
            end
        end

        % Button pushed function: PreviewButton
        function preview(app, event)
            % tell main app to build preview and make sure this window is
            % not interactable
            app.updateParams();
            app.callingApp.buildPreview(app.params);
            app.setActive(false);
        end

        % Value changed function: PassesEditField
        function chngPasses(app, event)
            value = app.PassesEditField.Value;
            if (app.InitialPassesEditField.Value < value)
                app.InitialPassesEditField.Value = value;
            end
        end

        % Value changed function: SaveVidCheckBox
        function saveVid(app, event)
            value = app.SaveVidCheckBox.Value;
            app.SubtractBgCheckBox.Enable = value;
            app.CompressCheckBox.Enable = value;
        end

        % Value changed function: CompressCheckBox
        function compressVid(app, event)
            if (app.CompressCheckBox.Value)
                app.SubtractBgCheckBox.Value = true;
            end
        end

        % Value changed function: SmplRoiCheckBox
        function smplTrace(app, event)
            if (app.SmplRoiCheckBox.Value)
                app.ZeroTracesCheckBox.Enable = true;
            elseif (~app.AesRoiCheckBox.Value)
                app.ZeroTracesCheckBox.Enable = false;
            end
        end

        % Value changed function: AesRoiCheckBox
        function aesTrace(app, event)
            if (app.AesRoiCheckBox.Value)
                app.ZeroTracesCheckBox.Enable = true;
            elseif (~app.SmplRoiCheckBox.Value)
                app.ZeroTracesCheckBox.Enable = false;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Output Settings';
            app.UIFigure.Icon = fullfile(pathToMLAPP, '+images', 'thumbnail_compression_app.png');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.OtherPanel = uipanel(app.GridLayout);
            app.OtherPanel.Title = 'Other';
            app.GridLayout2 = uigridlayout(app.OtherPanel);
            app.MultiFileCheckBox = uicheckbox(app.GridLayout2);
            app.MultiFileCheckBox.Tooltip = {'Reuse segmentation and template frame across multiple videos'};
            app.MultiFileCheckBox.Text = 'Process Multiple Videos';
            app.ProjectionCheckBox = uicheckbox(app.GridLayout2);
            app.ProjectionCheckBox.Tooltip = {'Save time average of all frames'};
            app.ProjectionCheckBox.Text = 'Save Projection';
            app.SaveMasksCheckBox = uicheckbox(app.GridLayout2);
            app.SaveMasksCheckBox.Tooltip = {'Save all masks used to process data'};
            app.SaveMasksCheckBox.Text = 'Save Adjusted Masks';
            app.MotionCorrectionPanel = uipanel(app.GridLayout);
            app.MotionCorrectionPanel.Title = 'Motion Correction';
            app.GridLayout3 = uigridlayout(app.MotionCorrectionPanel);
            app.InitialtemplatewindowEditFieldLabel = uilabel(app.GridLayout3);
            app.InitialtemplatewindowEditFieldLabel.Tooltip = {'Number of frames used to form initial template of comparison for motion registration'};
            app.InitialtemplatewindowEditFieldLabel.Text = 'Initial Template Window:';
            app.IntlWindowEditField = uieditfield(app.GridLayout3, 'numeric');
            app.IntlWindowEditField.Limits = [1 Inf];
            app.IntlWindowEditField.RoundFractionalValues = 'on';
            app.IntlWindowEditField.Enable = 'off';
            app.IntlWindowEditField.Value = 200;
            app.TemporalsmoothingwindowEditFieldLabel = uilabel(app.GridLayout3);
            app.TemporalsmoothingwindowEditFieldLabel.Tooltip = {'number of frames averaged to form comparison frame for motion correction  (Gaussian profile dropping to 1/e^2 at the first and last frames of the window)'};
            app.TemporalsmoothingwindowEditFieldLabel.Text = 'Temporal Smoothing Window:';
            app.MtnWindowEditField = uieditfield(app.GridLayout3, 'numeric');
            app.MtnWindowEditField.Limits = [1 Inf];
            app.MtnWindowEditField.RoundFractionalValues = 'on';
            app.MtnWindowEditField.ValueChangedFcn = createCallbackFcn(app, @chkSmthVal, true);
            app.MtnWindowEditField.Enable = 'off';
            app.MtnWindowEditField.Value = 11;
            app.CorrectMtnCheckBox = uicheckbox(app.GridLayout3);
            app.CorrectMtnCheckBox.ValueChangedFcn = createCallbackFcn(app, @correctMtn, true);
            app.CorrectMtnCheckBox.Text = 'Correct Motion';
            app.MainChannelLabel = uilabel(app.GridLayout3);
            app.MainChannelLabel.Tooltip = {'Channel analyzed for motion correction'};
            app.MainChannelLabel.Text = 'Main Channel:';
            app.MainChannelEditField = uieditfield(app.GridLayout3, 'numeric');
            app.MainChannelEditField.Limits = [1 Inf];
            app.MainChannelEditField.RoundFractionalValues = 'on';
            app.MainChannelEditField.Enable = 'off';
            app.MainChannelEditField.Value = 1;
            app.CorrectionPassesEditFieldLabel = uilabel(app.GridLayout3);
            app.CorrectionPassesEditFieldLabel.Tooltip = {'Number of iterations of motion correction before processing output'};
            app.CorrectionPassesEditFieldLabel.Text = 'Correction Passes';
            app.PassesEditField = uieditfield(app.GridLayout3, 'numeric');
            app.PassesEditField.Limits = [1 Inf];
            app.PassesEditField.RoundFractionalValues = 'on';
            app.PassesEditField.ValueChangedFcn = createCallbackFcn(app, @chngPasses, true);
            app.PassesEditField.Enable = 'off';
            app.PassesEditField.Value = 1;
            app.SpatialBlurEditFieldLabel = uilabel(app.GridLayout3);
            app.SpatialBlurEditFieldLabel.Tooltip = {'Gaussian blur (1/e^2) diameter applied to template and comparison frames for motion registration'};
            app.SpatialBlurEditFieldLabel.Text = 'Spatial Blur';
            app.SpatialBlurEditField = uieditfield(app.GridLayout3, 'numeric');
            app.SpatialBlurEditField.Limits = [0 Inf];
            app.SpatialBlurEditField.Enable = 'off';
            app.FFTCheckBox = uicheckbox(app.GridLayout3);
            app.FFTCheckBox.Enable = 'off';
            app.FFTCheckBox.Tooltip = {'Perform cross-correlation with FFT'};
            app.FFTCheckBox.Text = 'FFT XCorrelation';
            app.InitialWindowPassesEditFieldLabel = uilabel(app.GridLayout3);
            app.InitialWindowPassesEditFieldLabel.Tooltip = {'Number of iterations of motion correction applied to frames forming the template'};
            app.InitialWindowPassesEditFieldLabel.Text = 'Initial Window Passes';
            app.InitialPassesEditField = uieditfield(app.GridLayout3, 'numeric');
            app.InitialPassesEditField.Limits = [1 Inf];
            app.InitialPassesEditField.RoundFractionalValues = 'on';
            app.InitialPassesEditField.Enable = 'off';
            app.InitialPassesEditField.Value = 1;
            app.VideoOutputPanel = uipanel(app.GridLayout);
            app.VideoOutputPanel.Title = 'Video Output';
            app.GridLayout4 = uigridlayout(app.VideoOutputPanel);
            app.CompressCheckBox = uicheckbox(app.GridLayout4);
            app.CompressCheckBox.ValueChangedFcn = createCallbackFcn(app, @compressVid, true);
            app.CompressCheckBox.Enable = 'off';
            app.CompressCheckBox.Tooltip = {'Set pixels outside of exposure mask to zero and apply pack bits compression'};
            app.CompressCheckBox.Text = 'Compress Video';
            app.SubtractBgCheckBox = uicheckbox(app.GridLayout4);
            app.SubtractBgCheckBox.Enable = 'off';
            app.SubtractBgCheckBox.Tooltip = {'Subtract background (pixels outside of exposure mask) mean from all pixels in output video'};
            app.SubtractBgCheckBox.Text = 'Subtract Background Mean';
            app.SaveVidCheckBox = uicheckbox(app.GridLayout4);
            app.SaveVidCheckBox.ValueChangedFcn = createCallbackFcn(app, @saveVid, true);
            app.SaveVidCheckBox.Tooltip = {'Save output as video'};
            app.SaveVidCheckBox.Text = 'Save Video';
            app.TraceExtractionPanel = uipanel(app.GridLayout);
            app.TraceExtractionPanel.Title = 'Trace Extraction';
            app.GridLayout5 = uigridlayout(app.TraceExtractionPanel);
            app.SmplRoiCheckBox = uicheckbox(app.GridLayout5);
            app.SmplRoiCheckBox.ValueChangedFcn = createCallbackFcn(app, @smplTrace, true);
            app.SmplRoiCheckBox.Enable = 'off';
            app.SmplRoiCheckBox.Tooltip = {'Save pixel values for each frame for each Sample ROI'};
            app.SmplRoiCheckBox.Text = 'Extract Sample ROI Trace';
            app.AesRoiCheckBox = uicheckbox(app.GridLayout5);
            app.AesRoiCheckBox.ValueChangedFcn = createCallbackFcn(app, @aesTrace, true);
            app.AesRoiCheckBox.Enable = 'off';
            app.AesRoiCheckBox.Tooltip = {'Save pixel values for each frame for each AES ROI'};
            app.AesRoiCheckBox.Text = 'Extract AES ROI Trace';
            app.BgMeanCheckBox = uicheckbox(app.GridLayout5);
            app.BgMeanCheckBox.Tooltip = {'Save mean and stdev of pixels outside exposure mask'};
            app.BgMeanCheckBox.Text = 'Extract Background Mean';
            app.SaveMtnCheckBox = uicheckbox(app.GridLayout5);
            app.SaveMtnCheckBox.Enable = 'off';
            app.SaveMtnCheckBox.Tooltip = {'Save pixel displacement calculated from motion correction'};
            app.SaveMtnCheckBox.Text = 'Save motion trace';
            app.OverlapCheckBox = uicheckbox(app.GridLayout5);
            app.OverlapCheckBox.Enable = 'off';
            app.OverlapCheckBox.Tooltip = {'Check if sample ROIs drift outside of illuminated pixels'};
            app.OverlapCheckBox.Text = 'Verify Sample/AES Overlap';
            app.ZeroTracesCheckBox = uicheckbox(app.GridLayout5);
            app.ZeroTracesCheckBox.Enable = 'off';
            app.ZeroTracesCheckBox.Tooltip = {'Subtract background pixel mean value from sample and aes trace data'};
            app.ZeroTracesCheckBox.Text = 'Zero Traces';
            app.ProcessButton = uibutton(app.GridLayout, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @process, true);
            app.ProcessButton.Text = 'Process';
            app.CancelButton = uibutton(app.GridLayout, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancel, true);
            app.CancelButton.Text = 'Cancel';
            app.PreviewButton = uibutton(app.GridLayout, 'push');
            app.PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @preview, true);
            app.PreviewButton.Text = 'Preview';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mtn_output_helper_app(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end