classdef preview_helper_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        FrameTimeLabel       matlab.ui.control.Label
        ReferenceTimeLabel   matlab.ui.control.Label
        GridLayout3          matlab.ui.container.GridLayout
        SliceDropDown        matlab.ui.control.DropDown
        SettingsButton       matlab.ui.control.Button
        ChannelDropDown      matlab.ui.control.DropDown
        FrameLabel           matlab.ui.control.Label
        PlayButton           matlab.ui.control.StateButton
        FrameFwdButton       matlab.ui.control.Button
        FrameBackButton      matlab.ui.control.Button
        ProgressAxes         matlab.ui.control.UIAxes
        OriginalFrameAxes    matlab.ui.control.UIAxes
        MotionFrameAxes      matlab.ui.control.UIAxes
        ComparisonFrameAxes  matlab.ui.control.UIAxes
        ReferenceFrameAxes   matlab.ui.control.UIAxes
    end

    %%%%%%%%%%%%%
    % helper app used to display preview frames of motion correction and
    % segmentation
    %%%%%%%%%%%%%

    properties (Access = private)
        % main app
        callingApp
        
        % visual data
        mtnVidRef
        orgVidRef
        refImg % reference image for motion correction
        cmpVid % what the computer sees when to xcorr
        mtnVid % the motion corrected video
        orgVid % the original video
        masks % sample masks to overlay over videos
        maskOp = 0.4; % mask opacity in viewer

        % handles for visual elements
        imhandleRef
        imhandleCmp
        imhandleMtn
        imhandleOrg
        dispImgCmp
        dispImgMtn
        dispImgOrg
        hitBar % scrubber
        frameBar
        playIcon
        pauseIcon

        % timer variables
        tmr
        playing
        framerate = 15;
        avg = false;
        rollingAvg = 0;

        % variables for tracking place in video
        index
        frames
        channel
        slice
        mainChannel
        channels
        slices

        PlaybackDlgBox
    end
    
    methods (Access = public)
        function closePlayback(app)
            if (~isempty(app.PlaybackDlgBox))
                app.PlaybackDlgBox = [];
            end
        end

        function playbackClbk(app, framerate, avg, rollingAvg)
            app.UIFigure.Pointer = 'watch';
            if (~isempty(app.PlaybackDlgBox))
                app.PlaybackDlgBox.UIFigure.Pointer = 'watch';
            end
            drawnow;

            if (framerate ~= app.framerate)
                app.tmr = [];
                app.framerate = framerate;
                app.tmr = timer('TimerFcn',@app.playNext,'ExecutionMode','fixedRate','Period',(round(1000/app.framerate)/1000),'TasksToExecute',Inf);
            end

            if (avg)
                if (~app.avg || (app.rollingAvg ~= rollingAvg))
                    app.avg = true;
                    app.rollingAvg = rollingAvg;
                    kernel = ones(1,1,1,1,rollingAvg)/rollingAvg;
                    app.mtnVid = convn(app.mtnVidRef,kernel,'same');
                    app.orgVid = convn(app.orgVidRef,kernel,'same');
                    app.loadFrame();
                end
            elseif (~avg && app.avg)
                app.avg = false;
                app.rollingAvg = rollingAvg;
                app.mtnVid = app.mtnVidRef;
                app.orgVid = app.orgVidRef;
                app.loadFrame();
            end
            app.UIFigure.Pointer = 'arrow';
            if (~isempty(app.PlaybackDlgBox))
                app.PlaybackDlgBox.UIFigure.Pointer = 'arrow';
            end
        end
    end

    methods (Access = private)
        % play videos
        function startVid(app)
            if (~app.playing)
                start(app.tmr);
                app.playing = true;
                app.PlayButton.Value = true;
                app.PlayButton.Icon = app.pauseIcon;
            end
        end

        % stop playing videos
        function stopVid(app)
            if (app.playing)
                stop(app.tmr);
                app.playing = false;
                app.PlayButton.Value = false;
                app.PlayButton.Icon = app.playIcon;
            end
        end

        % set next frame and call ui update functions
        function playNext(app,obj,evt)
            if (app.index < app.frames)
                app.index = app.index+1;
            else
                app.index = 1;
            end
                app.loadFrame();
                app.setBar();
        end

        % update scrubber
        function setBar(app)
            app.FrameLabel.Text = strcat(num2str(app.index),"/",num2str(app.frames));
            app.frameBar.Position = [0,0,app.index/app.frames,1];
        end

        % update ui showing current frame of videos
        function loadFrame(app)
            app.dispImgCmp(:,:,1) = app.cmpVid(:,:,app.slice,app.index) .* (1-app.maskOp*app.masks(:,:,app.slice,app.mainChannel)) + app.maskOp*app.masks(:,:,app.slice,app.mainChannel);
            app.dispImgMtn(:,:,1) = app.mtnVid(:,:,app.slice,app.channel,app.index) .* (1-app.maskOp*app.masks(:,:,app.slice,app.channel)) + app.maskOp*app.masks(:,:,app.slice,app.channel);
            app.dispImgOrg(:,:,1) = app.orgVid(:,:,app.slice,app.channel,app.index) .* (1-app.maskOp*app.masks(:,:,app.slice,app.channel)) + app.maskOp*app.masks(:,:,app.slice,app.channel);
            for ii = 2:3
                app.dispImgCmp(:,:,ii) = app.cmpVid(:,:,app.slice,app.index);
                app.dispImgMtn(:,:,ii) = app.mtnVid(:,:,app.slice,app.channel,app.index);
                app.dispImgOrg(:,:,ii) = app.orgVid(:,:,app.slice,app.channel,app.index);
            end

            app.imhandleCmp.CData = app.dispImgCmp;
            app.imhandleMtn.CData = app.dispImgMtn;
            app.imhandleOrg.CData = app.dispImgOrg;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp, refImg, cmpVid, mtnVid, orgVid, masks, mainChannel, refTime, frameTime)
            app.UIFigure.Visible = 'off';
            app.UIFigure.WindowState = 'normal';
            app.callingApp = callingApp;
            
            app.refImg = refImg;
            app.cmpVid = cmpVid;
            app.mtnVid = mtnVid;
            app.orgVid = orgVid;
            app.masks = masks;
            app.mtnVidRef = mtnVid;
            app.orgVidRef = orgVid;
            
            % build scrubber and make clickable
            app.frameBar = rectangle(app.ProgressAxes,'Position',[0,0,0.5,1]);
            app.frameBar.FaceColor = [0.3,0.3,0.3];
            app.hitBar = rectangle(app.ProgressAxes,'Position',[0,0,1,1]);
            app.frameBar.HitTest = "off";
            app.hitBar.HitTest = "off";
            app.ProgressAxes.Toolbar.Visible = "off";

            % misc startup settings
            app.tmr = timer('TimerFcn',@app.playNext,'ExecutionMode','fixedRate','Period',(round(1000/app.framerate)/1000),'TasksToExecute',Inf);
            absPath = fileparts(mfilename('fullpath'));
            app.playIcon = imread(fullfile(absPath,'+images/play.png'));
            app.pauseIcon = imread(fullfile(absPath,'+images/pause.png'));
            daspect(app.ComparisonFrameAxes,[1,1,1]);
            daspect(app.MotionFrameAxes,[1,1,1]);
            daspect(app.ReferenceFrameAxes,[1,1,1]);
            daspect(app.OriginalFrameAxes,[1,1,1]);
            refDispImg = repmat(refImg(:,:,1),1,1,3);
            app.dispImgCmp = refDispImg;
            app.dispImgMtn = refDispImg;
            app.dispImgOrg = refDispImg;
            app.imhandleRef = image(app.ReferenceFrameAxes, refDispImg);
            app.imhandleCmp = image(app.ComparisonFrameAxes, refDispImg);
            app.imhandleMtn = image(app.MotionFrameAxes, refDispImg);
            app.imhandleOrg = image(app.OriginalFrameAxes, refDispImg);
            set(app.ComparisonFrameAxes,'xlim',[0,size(refImg,2)]);
            set(app.ComparisonFrameAxes,'ylim',[0,size(refImg,1)]);
            set(app.MotionFrameAxes,'xlim',[0,size(refImg,2)]);
            set(app.MotionFrameAxes,'ylim',[0,size(refImg,1)]);
            set(app.ReferenceFrameAxes,'xlim',[0,size(refImg,2)]);
            set(app.ReferenceFrameAxes,'ylim',[0,size(refImg,1)]);
            set(app.OriginalFrameAxes,'xlim',[0,size(refImg,2)]);
            set(app.OriginalFrameAxes,'ylim',[0,size(refImg,1)]);
            app.playing = false;
            app.index = 1;
            app.frames = size(mtnVid,5);

            % setup slice selector
            app.slices = size(app.mtnVid,3);
            slopts = cell(1,app.slices);
            for ii=1:app.slices
                slopts{ii} = ['Slice ',num2str(ii)];
            end
            app.slice = 1;
            app.SliceDropDown.Items = slopts;
            app.SliceDropDown.ItemsData = 1:app.slices;
            app.SliceDropDown.Value = app.slice;

            % setup channel selector
            app.channels = size(app.mtnVid,4);
            chnlopts = cell(1,app.channels);
            for ii = 1:app.channels
                chnlopts{ii} = ['Channel ',num2str(ii)];
            end
            app.mainChannel = mainChannel;
            app.channel = mainChannel;
            app.ChannelDropDown.Items = chnlopts;
            app.ChannelDropDown.ItemsData = 1:app.channels;
            app.ChannelDropDown.Value = mainChannel;

            if (refTime>0)
                app.ReferenceTimeLabel.Text = strcat("Reference Calc. Time: ",num2str(refTime)," s");
            end

            if (frameTime>0)
                app.FrameTimeLabel.Text = strcat("Frame Calc. Time: ",num2str(frameTime)," s/frame");
            end

            % draw first frame and clean up
            app.loadFrame();
            app.setBar();
            helper_apps.centerApp(app,callingApp);
            app.UIFigure.WindowStyle = "modal";
            app.UIFigure.Visible = 'on';
        end

        % Button pushed function: FrameBackButton
        function prvFrame(app, event)
            if (app.index > 1)
                app.stopVid();
                app.index = app.index - 1;
                app.loadFrame();
                app.setBar();
            end
        end

        % Button pushed function: FrameFwdButton
        function advFrame(app, event)
            if (app.index < app.frames)
                app.stopVid();
                app.index = app.index+1;
                app.loadFrame();
                app.setBar();
            end
        end

        % Value changed function: PlayButton
        function playPause(app, event)
            if (~app.playing)
                app.startVid();
            else
                app.stopVid();
            end
        end

        % Value changed function: ChannelDropDown
        function chngChannel(app, event)
            % change color channel
            app.channel = app.ChannelDropDown.Value;
            if (~app.playing)
                app.loadFrame();
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.stopVid();
            app.callingApp.closePreview();
            delete(app);
        end

        % Button down function: ProgressAxes
        function barClk(app, event)
            % not sure if this is needed (data should not take long to
            % access)
            flag = false;
            if (app.playing)
                app.stopVid();
                flag = true;
            end

            % update video time based on where scrubber is clicked and
            % update ui
            coord = app.ProgressAxes.CurrentPoint;
            app.index = round(coord(1)*app.frames);
            if (app.index < 1)
                app.index = 1;
            end
            app.loadFrame();
            app.setBar();

            if (flag)
                app.startVid();
            end

        end

        % Button pushed function: SettingsButton
        function opnPlaybackSettings(app, event)
            if (app.playing)
                app.stopVid();
            end
            
            if (isempty(app.PlaybackDlgBox))
                app.UIFigure.Pointer = 'watch';
                drawnow;
                app.PlaybackDlgBox = helper_apps.playback_helper_app(app,app.framerate,(app.avg*app.rollingAvg));
                app.UIFigure.Pointer = 'arrow';
            end
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            switch event.Key
                case 'space'
                    app.playPause([]);
                case 'leftarrow'
                    app.prvFrame([]);
                case 'rightarrow'
                    app.advFrame([]);
            end
        end

        % Value changed function: SliceDropDown
        function chngSlice(app, event)
            app.slice = app.SliceDropDown.Value;
            app.imhandleRef.CData = repmat(app.refImg(:,:,app.slice),1,1,3);
            if (~app.playing)
                app.loadFrame();
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Preview';
            app.UIFigure.Icon = fullfile(pathToMLAPP, '+images', 'thumbnail_compression_app.png');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ReferenceFrameAxes = uiaxes(app.GridLayout);
            title(app.ReferenceFrameAxes, 'Reference Image')
            app.ReferenceFrameAxes.XTick = [];
            app.ReferenceFrameAxes.YTick = [];
            app.ComparisonFrameAxes = uiaxes(app.GridLayout);
            title(app.ComparisonFrameAxes, 'Comparison Video')
            app.ComparisonFrameAxes.XTick = [];
            app.ComparisonFrameAxes.YTick = [];
            app.MotionFrameAxes = uiaxes(app.GridLayout);
            title(app.MotionFrameAxes, 'Motion Corrected Video')
            app.MotionFrameAxes.XTick = [];
            app.MotionFrameAxes.YTick = [];
            app.OriginalFrameAxes = uiaxes(app.GridLayout);
            title(app.OriginalFrameAxes, 'Original Video')
            app.OriginalFrameAxes.XTick = [];
            app.OriginalFrameAxes.YTick = [];
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.ProgressAxes = uiaxes(app.GridLayout3);
            app.ProgressAxes.XTick = [];
            app.ProgressAxes.YTick = [];
            app.ProgressAxes.ButtonDownFcn = createCallbackFcn(app, @barClk, true);
            app.FrameBackButton = uibutton(app.GridLayout3, 'push');
            app.FrameBackButton.ButtonPushedFcn = createCallbackFcn(app, @prvFrame, true);
            app.FrameBackButton.Icon = fullfile(pathToMLAPP, '+images', 'framebackward.png');
            app.FrameBackButton.Text = '';
            app.FrameFwdButton = uibutton(app.GridLayout3, 'push');
            app.FrameFwdButton.ButtonPushedFcn = createCallbackFcn(app, @advFrame, true);
            app.FrameFwdButton.Icon = fullfile(pathToMLAPP, '+images', 'frameforward.png');
            app.FrameFwdButton.Text = '';
            app.PlayButton = uibutton(app.GridLayout3, 'state');
            app.PlayButton.ValueChangedFcn = createCallbackFcn(app, @playPause, true);
            app.PlayButton.Icon = fullfile(pathToMLAPP, '+images', 'play.png');
            app.PlayButton.Text = '';
            app.FrameLabel = uilabel(app.GridLayout3);
            app.FrameLabel.Text = '0/0';
            app.ChannelDropDown = uidropdown(app.GridLayout3);
            app.ChannelDropDown.Items = {'Channel 1'};
            app.ChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @chngChannel, true);
            app.ChannelDropDown.Value = 'Channel 1';
            app.SettingsButton = uibutton(app.GridLayout3, 'push');
            app.SettingsButton.ButtonPushedFcn = createCallbackFcn(app, @opnPlaybackSettings, true);
            app.SettingsButton.Text = 'Settings';
            app.SliceDropDown = uidropdown(app.GridLayout3);
            app.SliceDropDown.Items = {'Slice 1'};
            app.SliceDropDown.ValueChangedFcn = createCallbackFcn(app, @chngSlice, true);
            app.SliceDropDown.Value = 'Slice 1';
            app.ReferenceTimeLabel = uilabel(app.GridLayout);
            app.ReferenceTimeLabel.Text = 'Reference Calc. Time: NA';
            app.FrameTimeLabel = uilabel(app.GridLayout);
            app.FrameTimeLabel.Text = 'Frame Calc. Time: NA';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = preview_helper_app(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end