classdef roi_adjust_helper_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        MaskDropDown               matlab.ui.control.DropDown
        MaskLabel                  matlab.ui.control.Label
        CancelButton               matlab.ui.control.Button
        ApplyButton                matlab.ui.control.Button
        MarginSpinner              matlab.ui.control.Spinner
        MarginSpinnerLabel         matlab.ui.control.Label
        YSpinner                   matlab.ui.control.Spinner
        YdisplacementSpinnerLabel  matlab.ui.control.Label
        XSpinner                   matlab.ui.control.Spinner
        XdisplacementSpinnerLabel  matlab.ui.control.Label
    end

    %%%%%%%%%%%%%%%%%%%%
    % helper app used to select size/position modification settings for
    % different masks
    %%%%%%%%%%%%%%%%%%%%

    properties (Access = private)
        callingApp
    end
    
    methods (Access = public)
        
        % update fields
        function setFields(app, x, y, mgn, index, type)
            app.XSpinner.Value = x;
            app.YSpinner.Value = y;
            app.MarginSpinner.Value = mgn;

            if (type~=MaskType.NULL)
                app.MaskDropDown.Value = {index, type};
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp, hasMask, roiAesNames, roiSmplNames)
            app.UIFigure.Visible = 'off';
            app.UIFigure.WindowState = 'normal';
            app.callingApp = callingApp;
            % build drop down list to align with available masks
            % item data is two component cell (1 is index for aes and smpl
            % rois and 2 is mask type id)
            items = [];
            itemsData = cell(0);
            items = [items, ' '];
            itemsData{end+1} = {0,MaskType.NOMASK};
            if (hasMask)
                items = [items, "Exposure Mask"];
                itemsData{end+1} = {0,MaskType.EXPMASK};
            end

            if (~isempty(roiAesNames))
                items = [items, "All AES ROI's"];
                items = [items, strcat('AES: ',roiAesNames)];
                itemsData{end+1} = {0,MaskType.AESALL};
                for ii = 1:length(roiAesNames)
                    itemsData{end+1} = {ii,MaskType.AES};
                end
            end

            if (~isempty(roiSmplNames))
                items = [items, "All Sample ROI's"];
                items = [items, strcat('SMPL: ',roiSmplNames)];
                itemsData{end+1} = {0,MaskType.SMPLALL};
                for ii = 1:length(roiSmplNames)
                    itemsData{end+1} = {ii,MaskType.SMPL};
                end
            end

            app.MaskDropDown.Items = items;
            app.MaskDropDown.ItemsData = itemsData;
            app.MaskDropDown.Value = {0,MaskType.NOMASK};
            if ((callingApp.v(1)>9)||((callingApp.v(1)==9)&&(callingApp.v(2)>9)))
                app.UIFigure.WindowStyle = "alwaysontop";
            end
            helper_apps.centerApp(app,callingApp);
            app.UIFigure.Visible = 'on';
        end

        % Callback function: ApplyButton, UIFigure
        function UIFigureCloseRequest(app, event)
            app.callingApp.closeRoiAdj();
            delete(app);
        end

        % Button pushed function: CancelButton
        function cancel(app, event)
            % reverts all changes made while this window was open
            app.callingApp.revertProp();
            app.callingApp.closeRoiAdj();
            delete(app);
        end

        % Value changed function: MaskDropDown
        function chngMask(app, event)
            % make sure ui highlights selected mask
            value = app.MaskDropDown.Value;
            app.callingApp.chngMask(value{1},value{2});
        end

        % Value changed function: MarginSpinner, XSpinner, YSpinner
        function chngProp(app, event)
            % call main app to modify properties
            temp = app.MaskDropDown.Value;
            app.callingApp.setMaskProp(app.XSpinner.Value,app.YSpinner.Value,app.MarginSpinner.Value,temp{1},temp{2});
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Modify Masks';
            app.UIFigure.Icon = fullfile(pathToMLAPP, '+images', 'thumbnail_compression_app.png');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.XdisplacementSpinnerLabel = uilabel(app.GridLayout);
            app.XdisplacementSpinnerLabel.Text = 'X displacement:';
            app.XSpinner = uispinner(app.GridLayout);
            app.XSpinner.RoundFractionalValues = 'on';
            app.XSpinner.ValueChangedFcn = createCallbackFcn(app, @chngProp, true);
            app.YdisplacementSpinnerLabel = uilabel(app.GridLayout);
            app.YdisplacementSpinnerLabel.Text = 'Y displacement:';
            app.YSpinner = uispinner(app.GridLayout);
            app.YSpinner.RoundFractionalValues = 'on';
            app.YSpinner.ValueChangedFcn = createCallbackFcn(app, @chngProp, true);
            app.MarginSpinnerLabel = uilabel(app.GridLayout);
            app.MarginSpinnerLabel.Text = 'Margin:';
            app.MarginSpinner = uispinner(app.GridLayout);
            app.MarginSpinner.RoundFractionalValues = 'on';
            app.MarginSpinner.ValueChangedFcn = createCallbackFcn(app, @chngProp, true);
            app.ApplyButton = uibutton(app.GridLayout, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ApplyButton.Text = 'Apply';
            app.CancelButton = uibutton(app.GridLayout, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancel, true);
            app.CancelButton.Text = 'Cancel';
            app.MaskLabel = uilabel(app.GridLayout);
            app.MaskLabel.Text = 'Mask:';
            app.MaskDropDown = uidropdown(app.GridLayout);
            app.MaskDropDown.Items = {};
            app.MaskDropDown.ValueChangedFcn = createCallbackFcn(app, @chngMask, true);
            app.MaskDropDown.Value = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = roi_adjust_helper_app(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end