classdef AES_motion_correction_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        StackMenu               matlab.ui.container.Menu
        LoadReferenceStackMenu  matlab.ui.container.Menu
        BidirectionalShiftMenu  matlab.ui.container.Menu
        WindowLevelMenu         matlab.ui.container.Menu
        ManageInputVideosMenu   matlab.ui.container.Menu
        ProcessVideoMenu        matlab.ui.container.Menu
        ROIMenu                 matlab.ui.container.Menu
        LoadExposureMaskMenu    matlab.ui.container.Menu
        RemoveExposureMaskMenu  matlab.ui.container.Menu
        LoadAESROIsMenu         matlab.ui.container.Menu
        LoadSampleROIsMenu      matlab.ui.container.Menu
        AutodetectROIsMenu      matlab.ui.container.Menu
        AdjustROIsMenu          matlab.ui.container.Menu
        SaveScreenshotMenu      matlab.ui.container.Menu
        GridLayout              matlab.ui.container.GridLayout
        ChannelSpinner          matlab.ui.control.Spinner
        ChannelSpinnerLabel     matlab.ui.control.Label
        SampleROIPanel          matlab.ui.container.Panel
        GridLayout3             matlab.ui.container.GridLayout
        SmplOutlineCheckBox     matlab.ui.control.CheckBox
        RoiSmplRenameButton     matlab.ui.control.Button
        RoiSmplRemoveButton     matlab.ui.control.Button
        RoiSmplAddButton        matlab.ui.control.Button
        RoiSmplListbox          matlab.ui.control.ListBox
        AESROIPanel             matlab.ui.container.Panel
        GridLayout2             matlab.ui.container.GridLayout
        AesOutlineCheckBox      matlab.ui.control.CheckBox
        RoiAesRenameButton      matlab.ui.control.Button
        RoiAesRemoveButton      matlab.ui.control.Button
        RoiAesAddButton         matlab.ui.control.Button
        RoiAesListbox           matlab.ui.control.ListBox
        ImageAxes               matlab.ui.control.UIAxes
    end

    %%%%%%%%%%%%%%%%%%%%%%%
    % App for processing videos made with AES
    % Can handle rigid motion correction, compression, trace extraction
    % background subtraction, deinterlacing of slow axis bidirectional data
    %%%%%%%%%%%%%%%%%%%%%%%

    properties (Access = private)
        % file info
        refFile
        refPath
        vidFiles
        vidPaths

        % Visualizing elements
        refFrameLimit = 1500;
        userFrameLimit
        refImages
        shiftImage
        contrImage
        windows
        roiAesFile
        roiSmplFile
        roiAesSlct
        roiSmplSlct
        alpha = 0.25;
        alphaExp = 0.35;
        alphaSmpl = 0.25;
        alphaAes = 0.25;
        dispImage
        imhandle
        channel
        maskManager
        cntlDown

        % bidirectional
        bidirectional
        bidiShift

        % properties used when processing videos
        channels
        splitChannels
        mainChannel
        fullMask

        % helper apps
        interruptDialog
        chnlDlgBox
        bcDlgBox
        roiAdjDlgBox
        vidsDlgBox
        bidiShiftDlgBox
        outputDlgBox
        previewBox
        previewParamsDlgBox
        roiDtctDlgBox
    end
    
    properties (Access = public)
        % matlab version
        v
    end
    
    methods (Access = public)
        
        %% Image display functions
        % loads reference image (called from helper app)
        function chnlClbk(app, channels, mainChannel, frameLimit, bidirectional, splitChannels)
            delete(app.chnlDlgBox);
            app.chnlDlgBox = [];
            if (isempty(app.vidsDlgBox))
                figure(app.UIFigure);
            end
            app.interruptDialog.Title = "Opening file...";
            if (channels > 0)
                app.interruptDialog.Cancelable = "off";

                if (frameLimit == 0)
                    frameLimit = app.refFrameLimit;
                end
                app.userFrameLimit = frameLimit;

                ff = fullfile(app.refPath,app.refFile);
                params.bidirectional = bidirectional;
                params.channels = channels;
                params.bidiShift = 0;
                stackManager = StackTracker(params);
                app.refImages = stackManager.buildProjection(ff,frameLimit,app.interruptDialog);

                app.bidirectional = bidirectional;
                app.BidirectionalShiftMenu.Enable = bidirectional;
                if (bidirectional)
                    app.ChannelSpinner.RoundFractionalValues = false;
                    app.ChannelSpinner.Step = 0.5;
                    app.ChannelSpinner.Limits = [1,(channels+0.5)];
                else
                    if (channels > 1)
                        app.ChannelSpinner.Limits = [1,channels];
                    else
                        app.ChannelSpinner.Limits = [1,1.1];
                    end
                    app.ChannelSpinner.Step = 1;
                    app.ChannelSpinner.RoundFractionalValues = true;
                end

                for ii = 1:size(app.refImages,3)
                    mx = max(app.refImages(:,:,ii),[],'all');
                    mn = min(app.refImages(:,:,ii),[],'all');
                    if (mx == mn)
                        app.refImages(:,:,ii) = 0 * app.refImages(:,:,ii);
                    else
                        app.refImages(:,:,ii) = (app.refImages(:,:,ii) - mn) / (mx - mn);
                    end
                end

                app.splitChannels = splitChannels;
                app.ImageAxes.Title.String = strcat("file: ", app.refFile);
                app.windows = [zeros(1,channels); ones(1,channels)];
                app.channel = mainChannel;
                app.ChannelSpinner.Value = app.channel;
                app.bidiShift = 0;
                app.shiftImage = squeeze(app.refImages(:,:,((1+app.bidirectional)*app.channel-app.bidirectional)));
                app.contrImage = app.shiftImage;
                
                compatibleVid = true;
                if (isempty(app.maskManager) || (prod(app.maskManager.dim==size(app.shiftImage))==0) || (channels~=app.maskManager.channels) || (splitChannels~=app.maskManager.splitCh))
                    compatibleVid = false;
                    app.maskManager = MaskHandler(splitChannels,channels,mainChannel,[size(app.refImages,1),size(app.refImages,2)]);
                    app.RoiSmplListbox.Items = cell(0);
                    app.RoiAesListbox.Items = cell(0);
                    app.RoiAesAddButton.Enable = true;
                    app.LoadAESROIsMenu.Enable = true;
                    app.RoiSmplAddButton.Enable = true;
                    app.LoadSampleROIsMenu.Enable = true;
                    app.LoadExposureMaskMenu.Enable = true;
                    app.RemoveExposureMaskMenu.Enable = true;
                    app.AutodetectROIsMenu.Enable = true;
                    app.ProcessVideoMenu.Enable = true;
                end
                
                app.initImage(size(app.shiftImage));
                app.maskManager.showOutlineAes = app.AesOutlineCheckBox.Value;
                app.maskManager.showOutlineSmpl = app.SmplOutlineCheckBox.Value;
                app.maskManager.buildRoiImage();
                app.updateImage();

                tempFile = {app.refFile};
                tempPath = {app.refPath};
                if (isempty(app.vidFiles) || ~compatibleVid)
                    app.vidFiles = tempFile;
                    app.vidPaths = tempPath;
                else
                    chk = false;
                    ind = 1;
                    for ii = 1:length(app.vidFiles)
                        if (isequal(app.vidFiles(ii),tempFile(1)))
                            chk = 1;
                            ind = ii;
                        end
                    end
        
                    if (chk)
                        order = 1:length(app.vidFiles);
                        order = [ind, order(order~=ind)];
                        app.vidFiles = app.vidFiles(order);
                        app.vidPaths = app.vidPaths(order);
                    else
                        app.vidFiles = [tempFile, app.vidFiles];
                        app.vidPaths = [tempPath, app.vidPaths];
                    end
                end
                
                if(~isempty(app.vidsDlgBox))
                    app.vidsDlgBox.updateRef(app.vidFiles, app.vidPaths);
                end

                app.channels = channels;
                app.mainChannel = mainChannel;
                if (app.bidirectional)
                    app.chngYShift(app.autoYShift());
                end
                app.ManageInputVideosMenu.Enable = true;
                app.WindowLevelMenu.Enable = true;
                app.enableButtons(true);
            end
            close(app.interruptDialog);
            app.interruptDialog = [];
        end

        % called to setup image axis when an image is loaded
        function initImage(app,dimension)
            app.dispImage = zeros(dimension(1),dimension(2),3);
            app.imhandle = image(app.ImageAxes, app.dispImage,'ButtonDownFcn',@app.ImgClk);
            set(app.ImageAxes,'xlim',[0,dimension(2)]);
            set(app.ImageAxes,'ylim',[0,dimension(1)]);
        end

        % used to push image components onto display axis
        function updateImage(app)
            roiSmplImage = app.maskManager.roiSmplImage;
            roiAesImage = app.maskManager.roiAesImage;
            roiSlctImage = app.maskManager.roiSlctImage;

            smplImage = (roiSmplImage - roiSlctImage) > 0;
            aesImage = (roiAesImage - roiSmplImage - roiSlctImage) > 0;
            expImage = (app.maskManager.getExpMask() - roiAesImage - roiSmplImage - roiSlctImage) > 0;

            
            app.dispImage(:,:,1) = app.contrImage .* (1 - (app.alphaSmpl * smplImage + app.alphaAes * aesImage)) + app.alphaSmpl * smplImage + app.alphaAes * aesImage;
            app.dispImage(:,:,2) = app.contrImage .* (1 - (app.alpha * roiSlctImage + app.alphaAes * aesImage)) + app.alpha * roiSlctImage + app.alphaAes * aesImage;
            app.dispImage(:,:,3) = app.contrImage .* (1 - app.alphaExp * (expImage)) + app.alphaExp * expImage;
            app.imhandle.CData = app.dispImage;
        end

        % called when clicking on the image
        function ImgClk(app,src,evt)
            coord = ceil(evt.IntersectionPoint);
            app.maskManager.selectCoord(coord,app.cntlDown);
            app.maskManager.buildRoiImage();
            app.updateImage();
            aesSlct = app.maskManager.roiAesSlct;
            smplSlct = app.maskManager.roiSmplSlct;

            if (~isempty(aesSlct))
                if (sum(aesSlct)==0)
                    app.RoiAesListbox.Value = cell(0);
                else
                    app.RoiAesListbox.Value = app.RoiAesListbox.ItemsData(aesSlct);
                end
            end

            if (~isempty(smplSlct))
                if (sum(smplSlct)==0)
                    app.RoiSmplListbox.Value = cell(0);
                else
                    app.RoiSmplListbox.Value = app.RoiSmplListbox.ItemsData(smplSlct);
                end
            end

            if (~isempty(app.roiAdjDlgBox))
                hit = false;
                if (~isempty(smplSlct))
                    if (sum(smplSlct)>0)
                        hit = true;
                        [temp,ind] = max(smplSlct);
                        prop = app.maskManager.getSmplProp(ind);
                        app.roiAdjDlgBox.setFields(prop(1),prop(2),prop(3),ind,MaskType.SMPL);
                    end
                end

                if (~hit && ~isempty(aesSlct))
                    if (sum(aesSlct)>0)
                        hit = true;
                        [temp, ind] = max(aesSlct);
                        prop = app.maskManager.getAesProp(ind);
                        app.roiAdjDlgBox.setFields(prop(1),prop(2),prop(3),ind,MaskType.AES);
                    end
                end

                if (~hit)
                    app.roiAdjDlgBox.setFields(0,0,0,0,MaskType.NOMASK);
                end
            end
        end

        % used for changing window level of reference display
        function chngImgBnds(app,bounds)
            app.windows(:,floor(app.channel)) = [bounds(1); bounds(2)];
            tempMaskLow = app.shiftImage > bounds(1);
            tempMaskHigh = app.shiftImage > bounds(2);
            app.contrImage = tempMaskHigh + (tempMaskLow .* (1 - tempMaskHigh)) .* (app.shiftImage - bounds(1)) / (bounds(2) - bounds(1));
            app.updateImage();
        end

        % changes the bidirectional y offset and updates UI
        function chngYShift(app, shift)
            app.bidiShift = shift;
            if (mod(app.channel,1) == 0)
                app.shiftImage = circshift(squeeze(app.refImages(:,:,(2*app.channel-1))),app.bidiShift,1);
                app.chngImgBnds(app.windows(:,floor(app.channel)));
            end
        end

        % automatically calculates y offset between even and odd frames for
        % slow axis bidirectional data
        function shift = autoYShift(app)
            if (app.bidirectional)
                temp = xcorr2((app.refImages(:,:,(2*app.mainChannel-1))-mean(app.refImages(:,:,(2*app.mainChannel-1)),'all')),(app.refImages(:,:,(2*app.mainChannel))-mean(app.refImages(:,:,(2*app.mainChannel)),'all')));
                temp = temp(:,size(app.shiftImage,2));
                [t, ind] = max(temp);
                app.bidiShift = size(app.shiftImage,1)-ind;
                if (mod(app.channel,1)==0)
                    app.shiftImage = circshift(app.refImages(:,:,(2*app.channel-1)),app.bidiShift,1);
                    app.chngImgBnds(app.windows(:,floor(app.channel)));
                end

                shift = app.bidiShift;
            end
        end

        % sets button activity for mask fields based on usability
        function enableButtons(app,enable)
            if (~isempty(app.maskManager))
                if (enable)
                    hasAes = false;
                    if (app.maskManager.getNumAes() > 0)
                        hasAes = true;
                        app.RoiAesRemoveButton.Enable = true;
                        app.RoiAesRenameButton.Enable = true;
                    else
                        app.RoiAesRemoveButton.Enable = false;
                        app.RoiAesRenameButton.Enable = false;
                    end
    
                    hasSmpl = false;
                    if (app.maskManager.getNumSmpl() > 0)
                        hasSmpl = true;
                        app.RoiSmplRemoveButton.Enable = true;
                        app.RoiSmplRenameButton.Enable = true;
                    else
                        app.RoiSmplRemoveButton.Enable = false;
                        app.RoiSmplRenameButton.Enable = false;
                    end
    
                    if (hasAes || hasSmpl || app.maskManager.hasExpMask())
                        app.AdjustROIsMenu.Enable = true;
                    else
                        app.AdjustROIsMenu.Enable = false;
                    end
                else
                    app.RoiAesRemoveButton.Enable = false;
                    app.RoiAesRenameButton.Enable = false;
                    app.RoiSmplRemoveButton.Enable = false;
                    app.RoiSmplRenameButton.Enable = false;
                end
                app.ROIMenu.Enable = enable;
                app.StackMenu.Enable = enable;
                app.RoiAesAddButton.Enable = enable;
                app.RoiSmplAddButton.Enable = enable;
                app.RoiAesListbox.Enable = enable;
                app.RoiSmplListbox.Enable = enable;
                app.AesOutlineCheckBox.Enable = enable;
                app.SmplOutlineCheckBox.Enable = enable;
                app.ChannelSpinner.Enable = ((app.channels>1)||app.bidirectional)&&enable;
                app.SaveScreenshotMenu.Enable = enable;
            end
        end

        %% functions handling/closing helper apps
        function closeBC(app)
            app.bcDlgBox = [];
            app.enableButtons(true);
        end

        function closeRoiAdj(app)
            app.roiAdjDlgBox = [];
            app.enableButtons(true);
        end

        function closeVids(app)
            app.vidsDlgBox = [];
        end

        function closeOutputDlg(app)
            close(app.interruptDialog);
            app.outputDlgBox = [];
        end

        function closeShift(app)
            app.bidiShiftDlgBox = [];
            app.enableButtons(true);
        end

        function buildPreview(app, params)
            app.UIFigure.Pointer = 'watch';
            if (~isempty(app.outputDlgBox))
                app.outputDlgBox.UIFigure.Pointer = 'watch';
            end
            drawnow;
            app.previewParamsDlgBox = helper_apps.preview_params_helper_app(app,app.vidFiles,params);
            app.UIFigure.Pointer = 'arrow';
            if (~isempty(app.outputDlgBox))
                app.outputDlgBox.UIFigure.Pointer = 'arrow';
            end
        end

        % callback used for building a preview window showing motion
        % correction for a set range of frames of one video
        function previewParamsClbk (app, params, index, startFrame, endFrame)
            delete(app.previewParamsDlgBox);
            app.previewParamsDlgBox = [];
            % index 0 means the user hit cancel
            if (index == 0)
                app.outputDlgBox.setActive(true);
                return;
            end

            % setup file reader and generate reference image if relevant
            if (params.mtn)
                stackManager = MotionTracker(params);
                if ((app.v(1) < 9) || ((app.v(1)==9) && (app.v(2)<14)))
                    temp = round(app.channels*max((endFrame+params.mtnWndw*(1+app.bidirectional)),params.mtnIntlWndw)/(app.bidirectional+1));
                    stackManager.frameLimit = min(64000,temp);
                else
                    stackManager.frameLimit = round(app.channels*max((endFrame+params.mtnWndw*(1+app.bidirectional)),params.mtnIntlWndw)/(app.bidirectional+1));
                end
                app.outputDlgBox.interruptDialog.Title = 'Building Reference...';
                app.outputDlgBox.interruptDialog.Indeterminate = 'on';
                stackManager.setup(fullfile(app.vidPaths{1},app.vidFiles{1}));
                refImage = double(stackManager.getReference());
                maxVal = max(refImage,[],'all');
                minVal = min(refImage,[],'all');
                refImage = (refImage-minVal)/(maxVal-minVal);
            else
                stackManager = StackTracker(params);
                if ((app.v(1) < 9) || ((app.v(1)==9) && (app.v(2)<14)))
                    stackManager.frameLimit = min(64000,(app.channels*endFrame/(app.bidirectional+1)));
                else
                    stackManager.frameLimit = app.channels*endFrame/(app.bidirectional+1);
                end
                stackManager.open(fullfile(app.vidPaths{1},app.vidFiles{1}));
                stackManager.readFrame();
                refImage = double(0*stackManager.getImage(1));
                stackManager.close();
            end
            
            % open stack manager and setup matrices to read data into
            stackManager.open(fullfile(app.vidPaths{index},app.vidFiles{index}));
            frames = stackManager.getNumFrames();
            endFrame = min(endFrame,frames);
            startFrame = min(startFrame,(endFrame-1));
            frames = endFrame-startFrame+1;
            mtnVid = zeros(size(refImage,1),size(refImage,2),app.channels,frames);
            cmpVid = zeros(size(refImage,1),size(refImage,2),frames);
            orgVid = zeros(size(refImage,1),size(refImage,2),app.channels,frames);

            % setup progress dialog
            app.outputDlgBox.interruptDialog.Title = 'Processing Frames';
            app.outputDlgBox.interruptDialog.Indeterminate = 'off';    
            app.outputDlgBox.interruptDialog.Value = 0;     
            updateThreshold = round(frames*0.05);
            count = 0;
            % loop through passes if doing multiple passes
            for pass = 1:params.passes
                stackManager.setIndex(startFrame);
                lastPass = (pass == params.passes);
                if (params.passes > 1)
                    dlgstr = ['Pass (',num2str(pass),'/',num2str(params.passes),') Frame ('];
                else
                    dlgstr = 'Frame (';
                end
                app.outputDlgBox.interruptDialog.Message = [dlgstr,'0/',num2str(frames),')'];
                % loop through frames
                for fr = 1:frames
                    count = count + 1;
                    % update progress bar
                    if (mod(count,updateThreshold)==0)
                        app.outputDlgBox.interruptDialog.Message = [dlgstr,num2str(fr),'/',num2str(frames),')'];
                        app.outputDlgBox.interruptDialog.Value = ((pass-1)+fr/frames)/params.passes;
                    end

                    % read frame
                    stackManager.readFrame();
                    
                    % motion correct frame and store displaced image
                    if (params.mtn)
                        stackManager.calculateDisp();
                        if (lastPass)
                            [xDisp,yDisp] = stackManager.getDisp();
                            for ch = 1:app.channels
                                tempTiff = double(stackManager.getImage(ch));
                                maxVal = max(tempTiff,[],'all');
                                minVal = min(tempTiff,[],'all');
                                tempTiff = (tempTiff - minVal)/(maxVal-minVal);
                                orgVid(:,:,ch,fr) = tempTiff;
                                mtnVid(:,:,ch,fr) = circshift(tempTiff,[yDisp,xDisp]);
                            end
                            tempTiff = stackManager.getCompImage();
                            maxVal = max(tempTiff,[],'all');
                            minVal = min(tempTiff,[],'all');
                            cmpVid(:,:,fr) = (tempTiff-minVal)/(maxVal-minVal);
                        end
                    else
                        for ch = 1:app.channels
                            tempTiff = double(stackManager.getImage(ch));
                            maxVal = max(tempTiff,[],'all');
                            minVal = min(tempTiff,[],'all');
                            orgVid(:,:,ch,fr) = (tempTiff-minVal)/(maxVal-minVal);
                        end
                    end
                end
            end

            % store masks for overlay in preview window
            masks = zeros(size(refImage,1),size(refImage,2),app.channels);
            if (params.splitChannels)
                for ch = 1:app.channels
                    if (~isempty(params.roiSmpl{ch}))
                        masks(:,:,ch) = (sum(params.roiSmpl{ch},3)>0);
                    end
                end
            else
                if (~isempty(params.roiSmpl{1}))
                    mask = (sum(params.roiSmpl{1},3)>0);
                    for ch = 1:app.channels
                        masks(:,:,ch) = mask;
                    end
                end
            end

            % open preview window
            app.outputDlgBox.interruptDialog.Message = '';
            app.outputDlgBox.interruptDialog.Title = 'Done.';
            app.outputDlgBox.interruptDialog.Value = 1;
            app.UIFigure.Pointer = 'watch';
            if (~isempty(app.outputDlgBox))
                app.outputDlgBox.UIFigure.Pointer = 'watch';
            end
            drawnow;
            app.previewBox = helper_apps.preview_helper_app(app,refImage,cmpVid,mtnVid,orgVid,masks,params.mainChannel);
            app.UIFigure.Pointer = 'arrow';
            if (~isempty(app.outputDlgBox))
                app.outputDlgBox.UIFigure.Pointer = 'arrow';
            end
        end

        function closePreview(app)
            app.outputDlgBox.setActive(true);
        end

        %% functions handling masks
        % revert masks to values before helper app was opened
        function revertProp(app)
            app.maskManager.revert();
            app.maskManager.buildRoiImage();
            app.updateImage();
        end

        % adjust active fields in mask adjust helper app
        function chngMask(app,index,type)
            app.maskManager.selectRoiAes([]);
            app.maskManager.selectRoiSmpl([]);
            app.RoiAesListbox.Value = cell(0);
            app.RoiSmplListbox.Value = cell(0);
            switch type
                case MaskType.NULL
                    app.roiAdjDlgBox.setFields(0,0,0,0,MaskTyp.NULL);
                case MaskType.EXPMASK
                    prop = app.maskManager.getExpProp();
                    app.roiAdjDlgBox.setFields(prop(1),prop(2),prop(3),0,MaskType.NULL);
                case MaskType.AESALL
                    app.maskManager.flattenAesProp();
                    app.RoiAesListbox.Value = app.RoiAesListbox.ItemsData;
                    prop = app.maskManager.getAesProp(1);
                    app.roiAdjDlgBox.setFields(prop(1),prop(2),prop(3),0,MaskType.NULL);
                case MaskType.AES
                    prop = app.maskManager.getAesProp(index);
                    app.roiAdjDlgBox.setFields(prop(1),prop(2),prop(3),0,MaskType.NULL);
                    app.maskManager.selectRoiAes({index});
                    app.RoiAesListbox.Value = {index};
                case MaskType.SMPLALL
                    app.maskManager.flattenSmplProp();
                    app.RoiSmplListbox.Value = app.RoiSmplListbox.ItemsData;
                    prop = app.maskManager.getSmplProp(1);
                    app.roiAdjDlgBox.setFields(prop(1),prop(2),prop(3),0,MaskType.NULL);
                case MaskType.SMPL
                    prop = app.maskManager.getSmplProp(index);
                    app.roiAdjDlgBox.setFields(prop(1),prop(2),prop(3),0,MaskType.NULL);
                    app.maskManager.selectRoiSmpl({index});
                    app.RoiSmplListbox.Value = {index};
            end

            app.maskManager.buildRoiImage();
            app.updateImage();
        end

        % adjust masks (displacement and dialation)
        function setMaskProp(app, x, y, mgn, index, type)
            app.maskManager.setMaskProp(x,y,mgn,index,type);
            app.maskManager.buildRoiImage();
            app.updateImage();
        end

        % auto-generate AES and/or exposure masks based on minimum pixel thresholds
        function detectROIs(app,expThreshold,aesThreshold)
            if (isempty(app.roiDtctDlgBox))
                return;
            elseif (isempty(app.maskManager) || (expThreshold < 0) || (aesThreshold < 0))
                delete(app.roiDtctDlgBox);
                app.roiDtctDlgBox = [];
                return;
            end
            app.UIFigure.Pointer = 'watch';
            drawnow;

            % make the exposure mask
            if (expThreshold<1)
                tempExpMask = app.shiftImage > expThreshold;
                app.maskManager.setExpMask(tempExpMask);
                app.maskManager.setMaskProp(0,0,4,0,MaskType.EXPMASK);
            end

            % make the aes masks
            if (aesThreshold<1)
                % blob search algorithm
                mask = app.shiftImage > aesThreshold;
                x = repmat(1:size(app.shiftImage,2),size(app.shiftImage,1),1);
                y = repmat((1:size(app.shiftImage,1))',1,size(app.shiftImage,2));
                neighbors = [-1 0; 1 0; 0 -1; 0 1];
                seedInd = 1;
                subMask = [];
                numPx = numel(mask);

                while (~mask(seedInd) && (seedInd < numPx))
                    seedInd = seedInd + 1;
                end
                
                while (seedInd < numPx)
                    coord = [y(seedInd),x(seedInd)];
                    boundary = coord;
                    roi = (zeros(size(mask))>0);
                    searched = (zeros(size(mask))>0);
                    roi(coord(1),coord(2)) = true;
                    searched(coord(1),coord(2)) = true;

                    while(~isempty(boundary))
                        for ii = 1:length(neighbors)
                            coord = boundary(1,:) + neighbors(ii,:);
                            if ((coord(1) > 0) && (coord(2) > 0) && (coord(1) <= size(mask,1)) && (coord(2) <= size(mask,2)))
                                if (~searched(coord(1),coord(2)))
                                    searched(coord(1),coord(2)) = true;
                                    if (mask(coord(1),coord(2)))
                                        roi(coord(1),coord(2)) = true;
                                        boundary = [boundary; coord];
                                    end
                                end
                            end
                        end
                        boundary = boundary(2:end,:);
                    end
                    mask = mask - roi;
                    % filter out clumps of only a few pixels
                    if (sum(roi,'all')>5)
                        subMask = cat(3,subMask, roi);
                    end

                    while(~mask(seedInd) && seedInd < numPx)
                        seedInd = seedInd + 1;
                    end
                end
                subMask = subMask > 0;

                % add masks to model
                for ii = 1:size(subMask,3)
                    name = strcat('auto_aes_roi_',num2str(ii));
                    while(any(strcmp({name},app.roiAesFile)))
                        name = strcat(name,num2str(1));
                    end
                    app.maskManager.addRoiAes({name},subMask(:,:,ii));
                end

                names = app.maskManager.getAesNames();
                data = cell(length(names),1);
                for ii=1:length(names)
                    data{ii}=ii;
                end
                app.RoiAesListbox.Items = names;
                app.RoiAesListbox.ItemsData = data;
                app.slctRoiAes(0);
            end

            % update display and close helper window
            app.maskManager.buildRoiImage();
            app.updateImage();
            app.enableButtons(true);
            delete(app.roiDtctDlgBox);
            app.roiDtctDlgBox = [];
            app.UIFigure.Pointer = 'arrow';
        end

        %% Main function that processes the data
        function processVideos(app, params)
            outPath = uigetdir(app.refPath,'Output Directory');
            if (outPath==0)
                figure(app.UIFigure);
                if (~isempty(app.outputDlgBox))
                    figure(app.outputDlgBox.UIFigure);
                end
                drawnow;
                return;
            end
            
            delete(app.outputDlgBox);
            app.outputDlgBox = [];
            figure(app.UIFigure);
            drawnow;

            % make sure dimensions of video are valid
            app.interruptDialog.Title = "Setting Up...";
            app.interruptDialog.Indeterminate = "on";
            for ii = 1:length(app.vidFiles)
                ff = fullfile(app.vidPaths{ii},app.vidFiles{ii});
                tin = Tiff(ff,'r');
                tempTiff = double(read(tin));
                h = size(tempTiff);
                if (app.bidirectional)
                    h(1) = floor(h(1)/2);
                end
                
                if (~prod(isequal(h,size(app.shiftImage))))
                    opts.WindowStyle = 'non-modal';
                    waitfor(errordlg('Input video dimensions do not match','Dimension Mismatch',opts));
                    close(app.interruptDialog);
                    app.interruptDialog = [];
                    close(tin);
                    return;
                end
                close(tin);
            end

            % save editted masks
            if (params.saveMasks)
                [status, msg] = mkdir(outPath,'masks');
                if (~status)
                    opts.WindowStyle = 'non-modal';
                    waitfor(errordlg(msg,'Folder Write Issue',opts));
                    return;
                end
                maskPath = fullfile(outPath,'masks');

                if (params.splitChannels)
                    for ch = 1:app.channels
                        chFolder = strcat('channel_',num2str(ch));
                        [status, msg] = mkdir(maskPath,chFolder);
                        if (~status)
                            opts.WindowStyle = 'non-modal';
                            waitfor(errordlg(msg,'Folder Write Issue',opts));
                            return;
                        end
                        chPath = fullfile(maskPath,chFolder);

                        if (~isempty(params.roiAes{ch}))
                            [status, msg] = mkdir(chPath,'AES');
                            if (~status)
                                opts.WindowStyle = 'non-modal';
                                waitfor(errordlg(msg,'Folder Write Issue',opts));
                                return;
                            end
                            aesPath = fullfile(chPath,'AES');

                            for ii=1:size(params.roiAes{ch},3)
                                imwrite((squeeze(params.roiAes{ch}(:,:,ii))+0),fullfile(aesPath,strcat(params.aesNames{ch}{ii},'.tif')),'Compression','none');
                            end
                        end

                        if (~isempty(params.roiSmpl{ch}))
                            [status, msg] = mkdir(chPath,'sample');
                            if (~status)
                                opts.WindowStyle = 'non-modal';
                                waitfor(errordlg(msg,'Folder Write Issue',opts));
                                return;
                            end
                            smplPath = fullfile(chPath,'sample');

                            for ii=1:size(params.roiSmpl{ch},3)
                                imwrite((squeeze(params.roiSmpl{ch}(:,:,ii))+0),fullfile(smplPath,strcat(params.smplNames{ch}{ii},'.tif')),'Compression','none');
                            end
                        end

                        if (sum(params.expMask(:,:,ch),'all')>0)
                            imwrite((squeeze(params.expMask(:,:,ch))+0),fullfile(chPath,'exp_mask.tif'),'Compression','none');
                        end
                    end
                else
                    if (~isempty(params.roiAes{1}))
                        [status, msg] = mkdir(maskPath,'AES');
                        if (~status)
                            opts.WindowStyle = 'non-modal';
                            waitfor(errordlg(msg,'Folder Write Issue',opts));
                            return;
                        end
                        aesPath = fullfile(maskPath,'AES');

                        for ii=1:size(params.roiAes{1},3)
                            imwrite((squeeze(params.roiAes{1}(:,:,ii))+0),fullfile(aesPath,strcat(params.aesNames{1}{ii},'.tif')),'Compression','none');
                        end
                    end

                    if (~isempty(params.roiSmpl{1}))
                        [status, msg] = mkdir(maskPath,'sample');
                        if (~status)
                            opts.WindowStyle = 'non-modal';
                            waitfor(errordlg(msg,'Folder Write Issue',opts));
                            return;
                        end
                        smplPath = fullfile(maskPath,'sample');

                        for ii=1:size(params.roiSmpl{1},3)
                            imwrite((squeeze(params.roiSmpl{1}(:,:,ii))+0),fullfile(smplPath,strcat(params.smplNames{1}{ii},'.tif')),'Compression','none');
                        end
                    end

                    if (sum(params.expMask(:,:,1),'all')>0)
                        imwrite((squeeze(params.expMask(:,:,1))+0),fullfile(maskPath,'exp_mask.tif'),'Compression','none');
                    end
                end
            end

            % open motion tracker and generate reference image
            if (params.mtn)
                stackManager = MotionTracker(params);
                app.interruptDialog.Title = strcat("Building Reference...");
                if ((app.v(1) < 9) || ((app.v(1)==9) && (app.v(2)<14)))
                    disp('Matlab versions before 2023 cannot read large Tiff stacks. Limiting output processing to 64000 frames.')
                    stackManager.frameLimit = 64000;
                end
                stackManager.setup(fullfile(app.vidPaths{1},app.vidFiles{1}));
            else
                stackManager = StackTracker(params);
                if ((app.v(1) < 9) || ((app.v(1)==9) && (app.v(2)<14)))
                    disp('Matlab versions before 2023 cannot read large Tiff stacks. Limiting output processing to 64000 frames.')
                    stackManager.frameLimit = 64000;
                end
            end


            % loop through videos
            app.interruptDialog.Cancelable = "on";
            app.interruptDialog.Indeterminate = "off";
            app.interruptDialog.Value = 0;
            for file = 1:length(app.vidFiles)
                if (params.multiVid)
                    dlgstr = ['File (', num2str(file),'/',num2str(length(app.vidFiles)),'),', ' '];
                else
                    dlgstr = '';
                end

                % primary loop
                app.interruptDialog.Title = ['Opening:', ' ', app.vidFiles{file}];
                app.interruptDialog.Indeterminate = 'on';
                if (params.multiVid)
                    app.interruptDialog.Message = ['File (',num2str(file),'/',num2str(length(app.vidFiles)),')'];
                end
                app.interruptDialog.Value = 0; 
                % loop through correction passes (if doing multi-pass)
                for pass = 1:params.passes
                    lastPass = (params.passes == pass);
                    stackManager.open(fullfile(app.vidPaths{file},app.vidFiles{file}));
                    app.interruptDialog.Indeterminate = 'off';
                    
                    % count frames
                    if (pass == 1)
                        numFrames = stackManager.getNumFrames();
                        notify_count = round(numFrames / 20);
                    end

                    % build file output handler object
                    if (lastPass)
                        outputManager = OutputFileHandler(params);
                        status = outputManager.open(outPath,app.vidFiles{file},numFrames);
                        if (~status)
                            close(app.interruptDialog);
                            app.interruptDialog = [];
                            return;
                        end
                    end

                    app.interruptDialog.Title = ['Processing:', ' ', app.vidFiles{file}];
                    if (params.passes > 1)
                        app.interruptDialog.Message = [dlgstr, 'Pass (',num2str(pass),'/',num2str(params.passes),')'];
                        dlgstr2 = [dlgstr,'Pass (',num2str(pass),'/',num2str(params.passes),'),',' '];
                    else
                        dlgstr2 = dlgstr;
                    end

                    % loop through frames
                    for frame = 1:numFrames
                        % update progress bar and see if cancel is
                        % requested
                        if (mod(frame,notify_count)==0)
                            if (app.interruptDialog.CancelRequested)
                                app.interruptDialog.Title = "Closing...";
                                stackManager.close();
                                if (lastPass)
                                    outputManager.close();
                                end

                                close(app.interruptDialog);
                                app.interruptDialog = [];
                                return;
                            end

                            app.interruptDialog.Message = [dlgstr2,'Frame (',num2str(frame),'/',num2str(numFrames),')'];
                            app.interruptDialog.Value = ((pass-1) + frame/numFrames)/params.passes;
                        end

                        % read frame and calculate displacement
                        stackManager.readFrame();
                        if params.mtn
                            stackManager.calculateDisp();
                            if (lastPass)
                                [xDisp,yDisp] = stackManager.getDisp();
                                outputManager.setDisp(xDisp,yDisp);
                            end
                        end

                        % process output
                        if (lastPass)
                            outputManager.setFrame(stackManager.getFrame());
                            outputManager.processFrame();
                        end
                    end

                    if (lastPass)
                        stackManager.close();
                        outputManager.close();
                    end
                end

                if (~params.multiVid)
                    break;
                end
            end

            % wrap up
            close(app.interruptDialog);
            app.interruptDialog = [];
            if (strcmp(app.UIFigure.WindowState,'minimized'))
                app.UIFigure.WindowState = 'normal';
            end
            figure(app.UIFigure);
            drawnow;
            box = msgbox('Operation Completed.','Done','modal');
            box.Visible = 'off';
            parentPos = app.UIFigure.Position;
            boxPos = box.Position;
            movegui(box,[round(parentPos(1)+(parentPos(3)-boxPos(3))/2),round(parentPos(2)+(parentPos(4)-boxPos(4))/2)]);
            box.Visible = 'on';
        end

        %% Miscellaneous
        function updateVidFiles(app,vidFiles,vidPaths)
            app.vidFiles = vidFiles;
            app.vidPaths = vidPaths;
        end

        % For loading reference stack from helper app
        function loadStackRelay(app, refFile, refPath)
            if(isempty(app.roiAdjDlgBox))
                app.refFile = refFile;
                app.refPath = refPath;
                app.interruptDialog = uiprogressdlg(app.UIFigure,"Title","Setting up...");
                app.chnlClbk(app.channels, app.mainChannel, app.userFrameLimit, app.bidirectional, app.splitChannels);
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ImageAxes.Title.Interpreter = 'none';
            disableDefaultInteractivity(app.ImageAxes);
            daspect(app.ImageAxes,[1,1,1]);
            temp = ver('Matlab');
            temp = split(temp.Version,'.');
            app.v = [str2double(temp{1}),str2double(temp{2})];
            app.maskManager = [];
            app.cntlDown = false;
        end

        % Menu selected function: LoadReferenceStackMenu
        function loadStackClbk(app, event)
            [tempFile, tempPath] = uigetfile('.tif');
            figure(app.UIFigure);
            if ~isequal(tempFile,0)
                app.refFile = tempFile;
                app.refPath = tempPath;
                app.interruptDialog = uiprogressdlg(app.UIFigure,"Title","Waiting for channel info...");
                app.UIFigure.Pointer = 'watch';
                drawnow;
                app.chnlDlgBox = helper_apps.channel_helper_app(app);
                app.UIFigure.Pointer = 'arrow';
            elseif (~isempty(app.vidsDlgBox))
                app.vidsDlgBox.setButtonsActive(true);
            end
        end

        % Callback function: LoadAESROIsMenu, RoiAesAddButton
        function loadRoiAes(app, event)
            if (isempty(app.shiftImage))
                return;
            end

            [tempFiles, tempPath] = uigetfile('.tif','Select AES ROI Files','MultiSelect','on'); 
            figure(app.UIFigure);
            if (~isequal(tempFiles,0))
                app.UIFigure.Pointer = 'watch';
                drawnow;
                if (~iscell(tempFiles))
                    tempFiles = {tempFiles};
                end

                names = app.maskManager.getAesNames();
                for ii=1:length(tempFiles)
                    splitName = split(tempFiles{ii},'.');
                    tempFiles{ii} = splitName{1};
                    if (~any(strcmp(tempFiles{ii},names)))
                        tempTiff = double(imread(fullfile(tempPath,strcat(tempFiles{ii},'.tif')),1));
                        if prod(size(tempTiff)==size(app.shiftImage))
                            app.maskManager.addRoiAes(tempFiles{ii},(tempTiff>1));
                        end
                    end
                end

                names = app.maskManager.getAesNames();
                data = cell(length(names),1);
                for ii=1:length(names)
                    data{ii}=ii;
                end
                app.RoiAesListbox.Items = names;
                app.RoiAesListbox.ItemsData = data;
                app.slctRoiAes(0);
                app.enableButtons(true);
                app.UIFigure.Pointer = 'arrow';
            end
        end

        % Value changed function: RoiAesListbox
        function slctRoiAes(app, event)
            values = app.RoiAesListbox.Value;
            app.maskManager.selectRoiAes(values);
            app.maskManager.buildRoiImage();
            app.updateImage();
        end

        % Callback function: LoadSampleROIsMenu, RoiSmplAddButton
        function loadRoiSmpl(app, event)
            if (isempty(app.shiftImage))
                return;
            end

            [tempFiles, tempPath] = uigetfile('.tif','Select Sample ROI Files','MultiSelect','on');
            figure(app.UIFigure);
            if (~isequal(tempFiles,0))
                app.UIFigure.Pointer = 'watch';
                drawnow;
                if (~iscell(tempFiles))
                    tempFiles = {tempFiles};
                end

                names = app.maskManager.getSmplNames();
                for ii=1:length(tempFiles)
                    splitName = split(tempFiles{ii},'.');
                    tempFiles{ii} = splitName{1};
                    if (~any(strcmp(tempFiles(ii),names)))
                        tempTiff = double(imread(fullfile(tempPath,strcat(tempFiles{ii},'.tif')),1));
                        if prod(size(tempTiff)==size(app.shiftImage))
                            app.maskManager.addRoiSmpl(tempFiles{ii},(tempTiff>1));
                        end
                    end
                end

                names = app.maskManager.getSmplNames();
                data = cell(length(names),1);
                for ii=1:length(names)
                    data{ii}=ii;
                end
                app.RoiSmplListbox.Items = names;
                app.RoiSmplListbox.ItemsData = data;
                app.RoiSmplListbox.Value = cell(0);
                app.slctRoiSmpl(0);
                app.enableButtons(true);
                app.UIFigure.Pointer = 'arrow';
            end
        end

        % Value changed function: RoiSmplListbox
        function slctRoiSmpl(app, event)
            values = app.RoiSmplListbox.Value;
            app.maskManager.selectRoiSmpl(values);
            app.maskManager.buildRoiImage();
            app.updateImage();
        end

        % Button pushed function: RoiAesRemoveButton
        function removeRoiAes(app, event)
            if (isempty(app.roiAdjDlgBox))
                indRemove = app.RoiAesListbox.Value;
                if (~isempty(indRemove))
                    num = app.maskManager.getNumAes();
                    toRemove = zeros(1,num);
                    if (iscell(indRemove))
                        for ii = 1:length(indRemove)
                            toRemove(indRemove{ii}) = 1;
                        end
                    else
                        toRemove(indRemove) = 1;
                    end
                    
                    app.maskManager.removeRoiAes(toRemove);
                    names = app.maskManager.getAesNames();
                    data = cell(length(names),1);
                    for ii = 1:length(names)
                        data{ii} = ii;
                    end
                    app.RoiAesListbox.Items = names;
                    app.RoiAesListbox.ItemsData = data;
                    app.RoiAesListbox.Value = cell(0);
                    app.slctRoiAes(0);
                    app.enableButtons(true);
                end
            end
        end

        % Button pushed function: RoiSmplRemoveButton
        function removeRoiSmpl(app, event)
            if (isempty(app.roiAdjDlgBox))
                indRemove = app.RoiSmplListbox.Value;
                if (~isempty(indRemove))
                    num = app.maskManager.getNumSmpl();
                    toRemove = zeros(1,num);
                    if (iscell(indRemove))
                        for ii = 1:length(indRemove)
                            toRemove(indRemove{ii}) = 1;
                        end
                    else
                        toRemove(indRemove) = 1;
                    end
                    
                    app.maskManager.removeRoiSmpl(toRemove);
                    names = app.maskManager.getSmplNames();
                    data = cell(length(names),1);
                    for ii = 1:length(names)
                        data{ii} = ii;
                    end
                    app.RoiSmplListbox.Items = names;
                    app.RoiSmplListbox.ItemsData = data;                    
                    app.slctRoiSmpl(0);
                    app.enableButtons(true);
                end
            end
        end

        % Menu selected function: LoadExposureMaskMenu
        function loadExpMask(app, event)
            if (isempty(app.shiftImage))
                return;
            end
            
            [tempFile, tempPath] = uigetfile('.tif');
            figure(app.UIFigure);
            drawnow;
            if ~isequal(tempFile,0)
                tempTiff = double(imread(fullfile(tempPath,tempFile),1));
                if (app.bidirectional)
                    htot = size(tempTiff,1);
                    h = floor(htot/2);
                    tempTiff = flipud(tempTiff((htot-h+1):htot,:));
                end

                if (prod(size(tempTiff)==size(app.shiftImage)))
                    app.maskManager.setExpMask(tempTiff > 0);
                    app.maskManager.buildRoiImage();
                    app.updateImage();
                    app.enableButtons(true);
                else
                    opts = struct('WindowStyle','modal','Interpreter','none');
                    errordlg("Mask dimensions do not match reference.","Dimension Mismatch",opts);
                    return;
                end
            end
        end

        % Menu selected function: WindowLevelMenu
        function openWL(app, event)
            if (isempty(app.bcDlgBox) && ~isempty(app.shiftImage))
                app.UIFigure.Pointer = 'watch';
                drawnow;
                app.enableButtons(false);
                app.ChannelSpinner.Enable = ((app.channels>1)||app.bidirectional);
                app.bcDlgBox = helper_apps.BC_helper_app(app,app.shiftImage,app.windows(:,floor(app.channel)));
                app.UIFigure.Pointer = 'arrow';
            end
        end

        % Menu selected function: AdjustROIsMenu
        function openRoiAdj(app, event)
            if (isempty(app.roiAdjDlgBox) && ~isempty(app.shiftImage))
                hasExpMask = (sum(app.maskManager.getExpMask(),'all')>0);
                aesNames = app.maskManager.getAesNames();
                smplNames = app.maskManager.getSmplNames();
                if (hasExpMask||~isempty(aesNames)||~isempty(smplNames))
                    app.UIFigure.Pointer = 'watch';
                    drawnow;
                    app.maskManager.storeState();
                    app.roiAdjDlgBox = helper_apps.roi_adjust_helper_app(app,hasExpMask,aesNames,smplNames);
                    app.maskManager.selectRoiAes([]);
                    app.maskManager.selectRoiSmpl([]);
                    app.RoiSmplListbox.Value = cell(0);
                    app.RoiAesListbox.Value = cell(0);
                    app.enableButtons(false);
                    app.maskManager.buildRoiImage();
                    app.updateImage();
                    app.UIFigure.Pointer = 'arrow';
                end
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if (~isempty(app.chnlDlgBox))
                delete(app.chnlDlgBox);
            end

            if (~isempty(app.bcDlgBox))
                delete(app.bcDlgBox);
            end

            if (~isempty(app.roiAdjDlgBox))
                delete(app.roiAdjDlgBox);
            end

            if(~isempty(app.vidsDlgBox))
                delete(app.vidsDlgBox);
            end

            if (~isempty(app.previewBox))
                delete(app.previewBox);
            end

            if (~isempty(app.previewParamsDlgBox))
                delete(app.previewParamsDlgBox);
            end

            if(~isempty(app.outputDlgBox))
                delete(app.outputDlgBox);
            end

            if (~isempty(app.bidiShiftDlgBox))
                delete(app.bidiShiftDlgBox);
            end

            if (~isempty(app.roiDtctDlgBox))
                delete(app.roiDtctDlgBox);
            end

            delete(app)
        end

        % Menu selected function: ManageInputVideosMenu
        function mngVids(app, event)
            if (isempty(app.vidsDlgBox))
                app.UIFigure.Pointer = 'watch';
                drawnow;
                app.vidsDlgBox = helper_apps.stack_select_helper_app(app,app.vidFiles,app.vidPaths);
                app.UIFigure.Pointer = 'arrow';
            end
        end

        % Menu selected function: ProcessVideoMenu
        function setupOutput(app, event)
            if (isempty(app.shiftImage))
                return;
            end

            if ((sum(app.maskManager.getExpMask(),'all')>0)||~isempty(app.maskManager.getAesNames())||~isempty(app.maskManager.getSmplNames()))
                app.UIFigure.Pointer = 'watch';
                drawnow;
                app.maskManager.buildRoiImage();
                params.channels = app.channels;
                params.mainChannel = app.mainChannel;
                params.bidirectional = app.bidirectional;
                params.bidiShift = app.bidiShift;
                params.multiVid = (length(app.vidFiles) > 1);
                params = app.maskManager.attachParams(params);
                app.interruptDialog = uiprogressdlg(app.UIFigure,"Title","Waiting for output settings...");
                app.outputDlgBox = helper_apps.mtn_output_helper_app(app,params);
                app.UIFigure.Pointer = 'arrow';
            end
        end

        % Menu selected function: AutodetectROIsMenu
        function opnDtctRoi(app, event)
            if (~isempty(app.maskManager) && ~isempty(app.shiftImage) && isempty(app.roiDtctDlgBox))
                app.UIFigure.Pointer = 'watch';
                drawnow;
                app.roiDtctDlgBox = helper_apps.auto_roi_helper_app(app,app.shiftImage,app.windows(:,floor(app.channel)));
                app.UIFigure.Pointer = 'arrow';
            end
        end

        % Value changed function: ChannelSpinner
        function chngChannel(app, event)
            if (~isempty(app.shiftImage) && isempty(app.roiAdjDlgBox))
                tempCh = app.channel;
                app.channel = app.ChannelSpinner.Value;
                if (app.bidirectional)
                    % can't set spinner to round to 0.5 values, so have to
                    % do it programatically
                    app.channel = round(2*app.ChannelSpinner.Value)/2;
                    if (app.channel < 1)
                        app.channel = 1;
                    elseif (app.channel > (app.channels+0.5))
                        app.channel = app.channels+0.5;
                    end
                    app.ChannelSpinner.Value = app.channel;

                    if (mod(app.channel,1)==0)
                        app.shiftImage = squeeze(circshift(app.refImages(:,:,(2*app.channel-1)),app.bidiShift,1));
                    else
                        app.shiftImage = squeeze(app.refImages(:,:,(2*app.channel-1)));
                    end
                else
                    app.shiftImage = squeeze(app.refImages(:,:,app.channel));
                end
    
                if (~isempty(app.bcDlgBox))
                    app.bcDlgBox.reinit(app.shiftImage,app.windows(:,floor(app.channel)));
                end

                if (app.splitChannels)
                    if (floor(tempCh)~=floor(app.channel))
                        app.maskManager.setChannel(floor(app.channel));
                        names = app.maskManager.getAesNames();
                        data = cell(length(names),1);
                        for ii=1:length(data)
                            data{ii}=ii;
                        end
                        app.RoiAesListbox.Items = names;
                        app.RoiAesListbox.ItemsData = data;
                        app.RoiAesListbox.Value = cell(0);

                        names = app.maskManager.getSmplNames();
                        data = cell(length(names),1);
                        for ii=1:length(data)
                            data{ii}=ii;
                        end
                        app.RoiSmplListbox.Items = names;
                        app.RoiSmplListbox.ItemsData = data;
                        app.RoiSmplListbox.Value = cell(0);
                        
                        if (isempty(app.bcDlgBox) && isempty(app.bidiShiftDlgBox))
                            app.enableButtons(true);
                        end
                        app.maskManager.buildRoiImage();
                    end
                end
                app.chngImgBnds(app.windows(:,floor(app.channel)));
            end
        end

        % Menu selected function: BidirectionalShiftMenu
        function opnShift(app, event)
            if (~isempty(app.shiftImage) && app.bidirectional && isempty(app.bidiShiftDlgBox))
                app.UIFigure.Pointer = 'watch';
                drawnow;
                app.bidiShiftDlgBox = helper_apps.bidirectional_shift_helper_app(app,app.bidiShift);
                app.enableButtons(false);
                app.ChannelSpinner.Enable = true;
                app.UIFigure.Pointer = 'arrow';
            end
        end

        % Button pushed function: RoiAesRenameButton
        function renameRoiAes(app, event)
            if (isempty(app.roiAdjDlgBox))
                toRename = app.RoiAesListbox.Value;
                if (~isempty(toRename))
                    toRename = toRename{1};
                    names = app.maskManager.getAesNames();

                    userInput = inputdlg('','Rename',[1,30],names(toRename));
                    if (~isempty(userInput))
                        if (~isequal(userInput,names{toRename}))
                            valid = false;
                            while (~valid)
                                if (any(strcmp(names,userInput)))
                                    userInput = strcat(userInput,'2');
                                else
                                    valid = true;
                                end
                            end
                            app.maskManager.renameRoiAes(toRename,userInput);
                            names(toRename) = userInput;
                            app.RoiAesListbox.Items = names;
                        end
                    end
                end
            end
        end

        % Button pushed function: RoiSmplRenameButton
        function renameRoiSmpl(app, event)
            if (isempty(app.roiAdjDlgBox))
                toRename = app.RoiSmplListbox.Value;
                if (~isempty(toRename))
                    toRename = toRename{1};
                    names = app.maskManager.getSmplNames();

                    userInput = inputdlg('','Rename',[1,30],names(toRename));
                    if (~isempty(userInput))
                        if (~isequal(userInput,names{toRename}))
                            valid = false;
                            while (~valid)
                                if (any(strcmp(names,userInput)))
                                    userInput = strcat(userInput,'2');
                                else
                                    valid = true;
                                end
                            end
                            app.maskManager.renameRoiSmpl(toRename,userInput);
                            names(toRename) = userInput;
                            app.RoiSmplListbox.Items = names;
                        end
                    end
                end
            end
        end

        % Menu selected function: RemoveExposureMaskMenu
        function removeExpMask(app, event)
            if (~isempty(app.maskManager))
                app.maskManager.removeExpMask();
                app.maskManager.buildRoiImage();
                app.updateImage();
                app.enableButtons(true);
            end
        end

        % Key press function: UIFigure
        function keyPressed(app, event)
            key = event.Key;
            if (strcmp(key,'control') && isempty(app.roiAdjDlgBox))
                app.cntlDown = true;
            end
        end

        % Key release function: UIFigure
        function keyReleased(app, event)
            key = event.Key;
            if (strcmp(key,'control'))
                app.cntlDown = false;
            end
        end

        % Value changed function: AesOutlineCheckBox
        function toggleAesOutline(app, event)
            app.maskManager.setOutlineAes(app.AesOutlineCheckBox.Value);
            app.alphaAes = app.alpha + app.maskManager.showOutlineAes*0.55;
            app.updateImage();
        end

        % Value changed function: SmplOutlineCheckBox
        function toggleSmplOutline(app, event)
            app.maskManager.setOutlineSmpl(app.SmplOutlineCheckBox.Value);
            app.alphaSmpl = app.alpha + app.maskManager.showOutlineSmpl*0.75;
            app.updateImage();
        end

        % Menu selected function: SaveScreenshotMenu
        function saveDisplay(app, event)
            [outFile,outPath] = uiputfile('*.png','Screenshot Name','screenshot.png');
            figure(app.UIFigure);
            if (isequal(outFile,0))
                return;
            end

            basename = split(outFile,'.');
            basename = basename{1};
            app.UIFigure.Pointer = 'watch';
            drawnow;
            imwrite(app.dispImage,fullfile(outPath,strcat(basename,'.png')));
            app.UIFigure.Pointer = 'arrow';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'AES Motion App';
            app.UIFigure.Icon = fullfile(pathToMLAPP, '+helper_apps', '+images', 'thumbnail_compression_app.png');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @keyPressed, true);
            app.UIFigure.KeyReleaseFcn = createCallbackFcn(app, @keyReleased, true);
            app.StackMenu = uimenu(app.UIFigure);
            app.StackMenu.Text = 'Stack';
            app.LoadReferenceStackMenu = uimenu(app.StackMenu);
            app.LoadReferenceStackMenu.MenuSelectedFcn = createCallbackFcn(app, @loadStackClbk, true);
            app.LoadReferenceStackMenu.Text = 'Load Reference Stack';
            app.BidirectionalShiftMenu = uimenu(app.StackMenu);
            app.BidirectionalShiftMenu.MenuSelectedFcn = createCallbackFcn(app, @opnShift, true);
            app.BidirectionalShiftMenu.Enable = 'off';
            app.BidirectionalShiftMenu.Text = 'Bidirectional Shift';
            app.WindowLevelMenu = uimenu(app.StackMenu);
            app.WindowLevelMenu.MenuSelectedFcn = createCallbackFcn(app, @openWL, true);
            app.WindowLevelMenu.Enable = 'off';
            app.WindowLevelMenu.Text = 'Window/Level';
            app.ManageInputVideosMenu = uimenu(app.StackMenu);
            app.ManageInputVideosMenu.MenuSelectedFcn = createCallbackFcn(app, @mngVids, true);
            app.ManageInputVideosMenu.Enable = 'off';
            app.ManageInputVideosMenu.Text = 'Manage Input Videos';
            app.ProcessVideoMenu = uimenu(app.StackMenu);
            app.ProcessVideoMenu.MenuSelectedFcn = createCallbackFcn(app, @setupOutput, true);
            app.ProcessVideoMenu.Enable = 'off';
            app.ProcessVideoMenu.Text = 'Process Video';
            app.ROIMenu = uimenu(app.UIFigure);
            app.ROIMenu.Text = 'ROI';
            app.LoadExposureMaskMenu = uimenu(app.ROIMenu);
            app.LoadExposureMaskMenu.MenuSelectedFcn = createCallbackFcn(app, @loadExpMask, true);
            app.LoadExposureMaskMenu.Enable = 'off';
            app.LoadExposureMaskMenu.Text = 'Load Exposure Mask';
            app.RemoveExposureMaskMenu = uimenu(app.ROIMenu);
            app.RemoveExposureMaskMenu.MenuSelectedFcn = createCallbackFcn(app, @removeExpMask, true);
            app.RemoveExposureMaskMenu.Enable = 'off';
            app.RemoveExposureMaskMenu.Text = 'Remove Exposure Mask';
            app.LoadAESROIsMenu = uimenu(app.ROIMenu);
            app.LoadAESROIsMenu.MenuSelectedFcn = createCallbackFcn(app, @loadRoiAes, true);
            app.LoadAESROIsMenu.Enable = 'off';
            app.LoadAESROIsMenu.Text = 'Load AES ROIs';
            app.LoadSampleROIsMenu = uimenu(app.ROIMenu);
            app.LoadSampleROIsMenu.MenuSelectedFcn = createCallbackFcn(app, @loadRoiSmpl, true);
            app.LoadSampleROIsMenu.Enable = 'off';
            app.LoadSampleROIsMenu.Text = 'Load Sample ROIs';
            app.AutodetectROIsMenu = uimenu(app.ROIMenu);
            app.AutodetectROIsMenu.MenuSelectedFcn = createCallbackFcn(app, @opnDtctRoi, true);
            app.AutodetectROIsMenu.Enable = 'off';
            app.AutodetectROIsMenu.Text = 'Autodetect ROIs';
            app.AdjustROIsMenu = uimenu(app.ROIMenu);
            app.AdjustROIsMenu.MenuSelectedFcn = createCallbackFcn(app, @openRoiAdj, true);
            app.AdjustROIsMenu.Enable = 'off';
            app.AdjustROIsMenu.Text = 'Adjust ROIs';
            app.SaveScreenshotMenu = uimenu(app.ROIMenu);
            app.SaveScreenshotMenu.MenuSelectedFcn = createCallbackFcn(app, @saveDisplay, true);
            app.SaveScreenshotMenu.Enable = 'off';
            app.SaveScreenshotMenu.Text = 'Save Screenshot';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ImageAxes = uiaxes(app.GridLayout);
            title(app.ImageAxes, 'Title')
            xlabel(app.ImageAxes, 'X')
            ylabel(app.ImageAxes, 'Y')
            zlabel(app.ImageAxes, 'Z')
            app.AESROIPanel = uipanel(app.GridLayout);
            app.AESROIPanel.Title = 'AES ROI';
            app.GridLayout2 = uigridlayout(app.AESROIPanel);
            app.RoiAesListbox = uilistbox(app.GridLayout2);
            app.RoiAesListbox.Items = {};
            app.RoiAesListbox.Multiselect = 'on';
            app.RoiAesListbox.ValueChangedFcn = createCallbackFcn(app, @slctRoiAes, true);
            app.RoiAesListbox.Value = {};
            app.RoiAesAddButton = uibutton(app.GridLayout2, 'push');
            app.RoiAesAddButton.ButtonPushedFcn = createCallbackFcn(app, @loadRoiAes, true);
            app.RoiAesAddButton.Enable = 'off';
            app.RoiAesAddButton.Text = 'Add';
            app.RoiAesRemoveButton = uibutton(app.GridLayout2, 'push');
            app.RoiAesRemoveButton.ButtonPushedFcn = createCallbackFcn(app, @removeRoiAes, true);
            app.RoiAesRemoveButton.Enable = 'off';
            app.RoiAesRemoveButton.Text = 'Remove';
            app.RoiAesRenameButton = uibutton(app.GridLayout2, 'push');
            app.RoiAesRenameButton.ButtonPushedFcn = createCallbackFcn(app, @renameRoiAes, true);
            app.RoiAesRenameButton.Enable = 'off';
            app.RoiAesRenameButton.Text = 'Rename';
            app.AesOutlineCheckBox = uicheckbox(app.GridLayout2);
            app.AesOutlineCheckBox.ValueChangedFcn = createCallbackFcn(app, @toggleAesOutline, true);
            app.AesOutlineCheckBox.Enable = 'off';
            app.AesOutlineCheckBox.Text = 'Outline';
            app.SampleROIPanel = uipanel(app.GridLayout);
            app.SampleROIPanel.Title = 'Sample ROI';
            app.GridLayout3 = uigridlayout(app.SampleROIPanel);
            app.RoiSmplListbox = uilistbox(app.GridLayout3);
            app.RoiSmplListbox.Items = {};
            app.RoiSmplListbox.Multiselect = 'on';
            app.RoiSmplListbox.ValueChangedFcn = createCallbackFcn(app, @slctRoiSmpl, true);
            app.RoiSmplListbox.Value = {};
            app.RoiSmplAddButton = uibutton(app.GridLayout3, 'push');
            app.RoiSmplAddButton.ButtonPushedFcn = createCallbackFcn(app, @loadRoiSmpl, true);
            app.RoiSmplAddButton.Enable = 'off';
            app.RoiSmplAddButton.Text = 'Add';
            app.RoiSmplRemoveButton = uibutton(app.GridLayout3, 'push');
            app.RoiSmplRemoveButton.ButtonPushedFcn = createCallbackFcn(app, @removeRoiSmpl, true);
            app.RoiSmplRemoveButton.Enable = 'off';
            app.RoiSmplRemoveButton.Text = 'Remove';
            app.RoiSmplRenameButton = uibutton(app.GridLayout3, 'push');
            app.RoiSmplRenameButton.ButtonPushedFcn = createCallbackFcn(app, @renameRoiSmpl, true);
            app.RoiSmplRenameButton.Enable = 'off';
            app.RoiSmplRenameButton.Text = 'Rename';
            app.SmplOutlineCheckBox = uicheckbox(app.GridLayout3);
            app.SmplOutlineCheckBox.ValueChangedFcn = createCallbackFcn(app, @toggleSmplOutline, true);
            app.SmplOutlineCheckBox.Enable = 'off';
            app.SmplOutlineCheckBox.Text = 'Outline';
            app.ChannelSpinnerLabel = uilabel(app.GridLayout);
            app.ChannelSpinnerLabel.Text = 'Channel:';
            app.ChannelSpinner = uispinner(app.GridLayout);
            app.ChannelSpinner.Limits = [1 1.1];
            app.ChannelSpinner.RoundFractionalValues = 'on';
            app.ChannelSpinner.ValueChangedFcn = createCallbackFcn(app, @chngChannel, true);
            app.ChannelSpinner.Enable = 'off';
            app.ChannelSpinner.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AES_motion_correction_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end