classdef trace_settings_helper_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        UserFunctionLabel             matlab.ui.control.Label
        SelectFunctionButton          matlab.ui.control.Button
        UserFunctionCheckBox          matlab.ui.control.CheckBox
        PhotonCalCheckBox             matlab.ui.control.CheckBox
        PhotonCalEditField            matlab.ui.control.NumericEditField
        PhotonCalValueEditFieldLabel  matlab.ui.control.Label
        SampleRateEditField           matlab.ui.control.NumericEditField
        SampleRateEditFieldLabel      matlab.ui.control.Label
        CancelButton                  matlab.ui.control.Button
        OkayButton                    matlab.ui.control.Button
        OverlapCheckBox               matlab.ui.control.CheckBox
        FilterEditField               matlab.ui.control.NumericEditField
        FilterFWHMHzLabel             matlab.ui.control.Label
        FilterCheckBox                matlab.ui.control.CheckBox
        SubtractBackgroundCheckBox    matlab.ui.control.CheckBox
    end

    %%%%%%%%%%%%%%%%%%%%%%
    % Helper app for adjusting how traces are processed before display
    %%%%%%%%%%%%%%%%%%%%%%
    
    properties (Access = private)
        callingApp
        traceHandler
        previousFnc
        previousUsingFnc
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp, traceHandler)
            app.callingApp = callingApp;
            app.UIFigure.Visible = 'off';
            app.UIFigure.WindowState = 'normal';
            parentPos = callingApp.UIFigure.Position;
            pos = app.UIFigure.Position;
            movegui(app.UIFigure,[round(parentPos(1)+parentPos(3)/2-pos(3)/2),round(parentPos(2)+parentPos(4)/2)-pos(4)/2]);
            app.traceHandler = traceHandler;

            if (traceHandler.hasBg)
                app.SubtractBackgroundCheckBox.Enable = true;
                app.SubtractBackgroundCheckBox.Value = traceHandler.sbtrBG;
            elseif (traceHandler.traceZeroed)
                app.SubtractBackgroundCheckBox.Value = true;
                app.SubtractBackgroundCheckBox.Enable = false;
            end

            if (traceHandler.hasOverlap)
                app.OverlapCheckBox.Enable = true;
                app.OverlapCheckBox.Value = traceHandler.shOver;
            end

            if (traceHandler.filtering)
                app.FilterCheckBox.Value = true;
                app.FilterEditField.Value = traceHandler.filterFwhm;
            else
                app.FilterCheckBox.Value = false;
                app.FilterEditField.Enable = false;
            end

            if (traceHandler.photonCal==0)
                app.PhotonCalCheckBox.Value = false;
                app.PhotonCalEditField.Enable = false;
                app.PhotonCalEditField.Value = 1;
            else
                app.PhotonCalCheckBox.Value = true;
                app.PhotonCalEditField.Enable = true;
                app.PhotonCalEditField.Value = traceHandler.photonCal;
            end

            if (traceHandler.hasSmpl)
                app.UserFunctionCheckBox.Enable = true;
                app.UserFunctionCheckBox.Value = traceHandler.usingFnc;
                app.UserFunctionLabel.Enable = traceHandler.usingFnc;
                app.previousUsingFnc = traceHandler.usingFnc;
                app.previousFnc = traceHandler.usrFnc;
                app.SelectFunctionButton.Enable = true;
                if (~isempty(traceHandler.usrFnc))
                    app.UserFunctionLabel.Text = func2str(app.previousFnc);
                end
            end

            app.SampleRateEditField.Value = traceHandler.fps;
            app.UIFigure.WindowStyle = "modal";
            app.UIFigure.Visible = 'on';
        end

        % Value changed function: FilterCheckBox
        function filter(app, event)
            app.FilterEditField.Enable = app.FilterCheckBox.Value;
        end

        % Button pushed function: OkayButton
        function okay(app, event)
            app.traceHandler.setFPS(app.SampleRateEditField.Value);
            app.traceHandler.subtractBG(app.SubtractBackgroundCheckBox.Value);
            app.traceHandler.showOverlap(app.OverlapCheckBox.Value);
            app.traceHandler.setFilter((app.FilterCheckBox.Value*app.FilterEditField.Value));
            app.traceHandler.setPhotonCal((app.PhotonCalCheckBox.Value*app.PhotonCalEditField.Value));
            app.traceHandler.setUsrFnc(app.UserFunctionCheckBox.Value,[]);
            app.traceHandler.buildTraces();
            app.callingApp.setTraceOps();
            delete(app);
        end

        % Button pushed function: CancelButton
        function cancel(app, event)
            app.traceHandler.usingFnc = app.previousUsingFnc;
            app.traceHandler.usrFnc = app.previousFnc;
            app.callingApp.closeTraceOps();
            delete(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.cancel(event);
        end

        % Value changed function: PhotonCalCheckBox
        function cal(app, event)
            app.PhotonCalEditField.Enable = app.PhotonCalCheckBox.Value;
        end

        % Button pushed function: SelectFunctionButton
        function slctUsrFnc(app, event)
            [tempFile, tempPath] = uigetfile('.m','Select function file');
            if (isequal(tempFile,0))
                return;
            end

            tempDir = cd;

            fncName = split(tempFile,'.');
            fncName = fncName{1};
            cd(tempPath);
            fnc = str2func(fncName);
            cd(tempDir);

            if (app.traceHandler.setUsrFnc(true,fnc))
                app.UserFunctionLabel.Text = fncName;
            end
        end

        % Value changed function: UserFunctionCheckBox
        function useFnc(app, event)
            app.UserFunctionLabel.Enable = app.UserFunctionCheckBox.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Trace Settings';
            app.UIFigure.Icon = fullfile(pathToMLAPP, '+images', 'trace_viewer_app_icon.png');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.SubtractBackgroundCheckBox = uicheckbox(app.GridLayout);
            app.SubtractBackgroundCheckBox.Enable = 'off';
            app.SubtractBackgroundCheckBox.Text = 'Subtract Background';
            app.FilterCheckBox = uicheckbox(app.GridLayout);
            app.FilterCheckBox.ValueChangedFcn = createCallbackFcn(app, @filter, true);
            app.FilterCheckBox.Text = 'Lowpass Filter';
            app.FilterFWHMHzLabel = uilabel(app.GridLayout);
            app.FilterFWHMHzLabel.Text = 'Filter FWHM (Hz)';
            app.FilterEditField = uieditfield(app.GridLayout, 'numeric');
            app.FilterEditField.Limits = [0 Inf];
            app.OverlapCheckBox = uicheckbox(app.GridLayout);
            app.OverlapCheckBox.Enable = 'off';
            app.OverlapCheckBox.Text = 'Show Out of Bounds';
            app.OkayButton = uibutton(app.GridLayout, 'push');
            app.OkayButton.ButtonPushedFcn = createCallbackFcn(app, @okay, true);
            app.OkayButton.Text = 'Okay';
            app.CancelButton = uibutton(app.GridLayout, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancel, true);
            app.CancelButton.Text = 'Cancel';
            app.SampleRateEditFieldLabel = uilabel(app.GridLayout);
            app.SampleRateEditFieldLabel.Text = 'Sample Rate';
            app.SampleRateEditField = uieditfield(app.GridLayout, 'numeric');
            app.SampleRateEditField.Limits = [1e-06 Inf];
            app.SampleRateEditField.Value = 1e-06;
            app.PhotonCalValueEditFieldLabel = uilabel(app.GridLayout);
            app.PhotonCalValueEditFieldLabel.Text = 'Photon Cal. Value';
            app.PhotonCalEditField = uieditfield(app.GridLayout, 'numeric');
            app.PhotonCalEditField.Limits = [0 Inf];
            app.PhotonCalEditField.Value = 1;
            app.PhotonCalCheckBox = uicheckbox(app.GridLayout);
            app.PhotonCalCheckBox.ValueChangedFcn = createCallbackFcn(app, @cal, true);
            app.PhotonCalCheckBox.Text = 'Calculate Photons';
            app.UserFunctionCheckBox = uicheckbox(app.GridLayout);
            app.UserFunctionCheckBox.ValueChangedFcn = createCallbackFcn(app, @useFnc, true);
            app.UserFunctionCheckBox.Enable = 'off';
            app.UserFunctionCheckBox.Text = 'User Function';
            app.SelectFunctionButton = uibutton(app.GridLayout, 'push');
            app.SelectFunctionButton.ButtonPushedFcn = createCallbackFcn(app, @slctUsrFnc, true);
            app.SelectFunctionButton.Enable = 'off';
            app.SelectFunctionButton.Text = 'Select Function';
            app.UserFunctionLabel = uilabel(app.GridLayout);
            app.UserFunctionLabel.Enable = 'off';
            app.UserFunctionLabel.Text = 'None';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = trace_settings_helper_app(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end